classdef Analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        AnalysisOptionsTab        matlab.ui.container.Tab
        Image_ELF                 matlab.ui.control.Image
        Image_Eigen               matlab.ui.control.Image
        Typepopup                 matlab.ui.control.DropDown
        text30_3                  matlab.ui.control.Label
        Image_IDA                 matlab.ui.control.Image
        Image_EQ_Sa               matlab.ui.control.Image
        Image_EQ_SF               matlab.ui.control.Image
        Image_PO                  matlab.ui.control.Image
        RunOptionsTab             matlab.ui.container.Tab
        text69_5                  matlab.ui.control.Label
        nCores                    matlab.ui.control.NumericEditField
        CBparallel                matlab.ui.control.CheckBox
        TipIcon6                  matlab.ui.control.Button
        editUncertainty           matlab.ui.control.EditField
        Sigmabutton               matlab.ui.control.Button
        uncertaintyCB             matlab.ui.control.CheckBox
        text32_5                  matlab.ui.control.Label
        text32_4                  matlab.ui.control.Label
        text32_3                  matlab.ui.control.Label
        edit03                    matlab.ui.control.NumericEditField
        edit02                    matlab.ui.control.NumericEditField
        edit01                    matlab.ui.control.NumericEditField
        ShowScopeCB               matlab.ui.control.CheckBox
        TipIcon5                  matlab.ui.control.Button
        edit00                    matlab.ui.control.NumericEditField
        text32_2                  matlab.ui.control.Label
        text31_2                  matlab.ui.control.Label
        checkbox4                 matlab.ui.control.CheckBox
        checkbox3                 matlab.ui.control.CheckBox
        PushoverELFParametersTab  matlab.ui.container.Tab
        editELF                   matlab.ui.control.EditField
        ELFbutton                 matlab.ui.control.Button
        text28_2                  matlab.ui.control.Label
        edit22                    matlab.ui.control.NumericEditField
        edit21                    matlab.ui.control.NumericEditField
        text32                    matlab.ui.control.Label
        text31                    matlab.ui.control.Label
        text30                    matlab.ui.control.Label
        DynamicParametersTab      matlab.ui.container.Tab
        text6_9                   matlab.ui.control.Label
        text39_7                  matlab.ui.control.Label
        coeff_T_upper             matlab.ui.control.NumericEditField
        coeff_T_lower             matlab.ui.control.NumericEditField
        text39_6                  matlab.ui.control.Label
        edit262                   matlab.ui.control.NumericEditField
        text39_5                  matlab.ui.control.Label
        text39_4                  matlab.ui.control.Label
        text6_7                   matlab.ui.control.Label
        text6_6                   matlab.ui.control.Label
        text6_5                   matlab.ui.control.Label
        text6_4                   matlab.ui.control.Label
        text6_3                   matlab.ui.control.Label
        text6_2                   matlab.ui.control.Label
        TipIcon4                  matlab.ui.control.Button
        edit28                    matlab.ui.control.NumericEditField
        text27_3                  matlab.ui.control.Label
        edit20                    matlab.ui.control.NumericEditField
        text27_2                  matlab.ui.control.Label
        checkbox44                matlab.ui.control.CheckBox
        edit19                    matlab.ui.control.NumericEditField
        edit18                    matlab.ui.control.NumericEditField
        edit16                    matlab.ui.control.NumericEditField
        edit6                     matlab.ui.control.NumericEditField
        edit9                     matlab.ui.control.NumericEditField
        text38                    matlab.ui.control.Label
        SApopup                   matlab.ui.control.DropDown
        text27                    matlab.ui.control.Label
        text20                    matlab.ui.control.Label
        text6                     matlab.ui.control.Label
        text16                    matlab.ui.control.Label
        text9                     matlab.ui.control.Label
        DampingTab                matlab.ui.container.Tab
        edit26                    matlab.ui.control.NumericEditField
        text39_2                  matlab.ui.control.Label
        edit25                    matlab.ui.control.NumericEditField
        edit24                    matlab.ui.control.NumericEditField
        text39                    matlab.ui.control.Label
        text35                    matlab.ui.control.Label
        text34                    matlab.ui.control.Label
        GroundMotionsTab          matlab.ui.container.Tab
        inspectGMButton           matlab.ui.control.Button
        text41_3                  matlab.ui.control.Label
        text6_8                   matlab.ui.control.Label
        editGM                    matlab.ui.control.EditField
        edit31                    matlab.ui.control.NumericEditField
        text41_2                  matlab.ui.control.Label
        GMbutton                  matlab.ui.control.Button
        edit30                    matlab.ui.control.NumericEditField
        text41                    matlab.ui.control.Label
        edit8                     matlab.ui.control.NumericEditField
        edit7                     matlab.ui.control.NumericEditField
        text8                     matlab.ui.control.Label
        text7                     matlab.ui.control.Label
        text28                    matlab.ui.control.Label
        RecordersTab              matlab.ui.container.Tab
        checkbox36                matlab.ui.control.CheckBox
        checkbox35                matlab.ui.control.CheckBox
        checkbox34                matlab.ui.control.CheckBox
        checkbox33                matlab.ui.control.CheckBox
        checkbox32                matlab.ui.control.CheckBox
        checkbox31                matlab.ui.control.CheckBox
        checkbox29                matlab.ui.control.CheckBox
        checkbox30                matlab.ui.control.CheckBox
        checkbox26                matlab.ui.control.CheckBox
        checkbox28                matlab.ui.control.CheckBox
        checkbox27                matlab.ui.control.CheckBox
        checkbox25                matlab.ui.control.CheckBox
        checkbox24                matlab.ui.control.CheckBox
        checkbox23                matlab.ui.control.CheckBox
        checkbox22                matlab.ui.control.CheckBox
        checkbox21                matlab.ui.control.CheckBox
        checkbox20                matlab.ui.control.CheckBox
        checkbox19                matlab.ui.control.CheckBox
        TsunamiPushoverTab        matlab.ui.container.Tab
        text69_4                  matlab.ui.control.Label
        Drag_Coeff                matlab.ui.control.NumericEditField
        text31_6                  matlab.ui.control.Label
        text38_3                  matlab.ui.control.Label
        SApopup_3                 matlab.ui.control.DropDown
        text70                    matlab.ui.control.Label
        ro_water                  matlab.ui.control.NumericEditField
        text31_5                  matlab.ui.control.Label
        text38_2                  matlab.ui.control.Label
        CDPOpattern               matlab.ui.control.DropDown
        FroudeNumber              matlab.ui.control.NumericEditField
        text31_4                  matlab.ui.control.Label
        text69                    matlab.ui.control.Label
        dinundation               matlab.ui.control.NumericEditField
        text31_3                  matlab.ui.control.Label
        TsunamiTimeHistoryTab     matlab.ui.container.Tab
        text69_3                  matlab.ui.control.Label
        editTsunami               matlab.ui.control.EditField
        Tsunamibutton             matlab.ui.control.Button
        text28_3                  matlab.ui.control.Label
        SubmitButton              matlab.ui.control.Button
    end


    properties (Access = private)
        MainApp;
        TipWindow;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Analysis_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';

            cluster = parcluster('local');
            maxWorkers = cluster.NumWorkers;
            app.nCores.Limits=[1 maxWorkers];

            global  ProjectName ProjectPath resource_root
            load(strcat(ProjectPath,ProjectName));

            clc;

            app.Image_Eigen.ImageSource = strjoin(resource_root+"Eigen.png");
            app.Image_PO.ImageSource    = strjoin(resource_root+"PO.png");
            app.Image_IDA.ImageSource   = strjoin(resource_root+"IDA.png");
            app.Image_EQ_SF.ImageSource = strjoin(resource_root+"EQ_SF.png");
            app.Image_EQ_Sa.ImageSource = strjoin(resource_root+"EQ_Sa.png");
            app.Image_ELF.ImageSource   = strjoin(resource_root+"ELF.png");

            if Units == 2
                app.text69.Text='in';
                app.text70.Text='lbs/in3';
            end

            if NStory>2
                app.edit21.Limits = [1 NStory];
                app.edit24.Limits = [1 NStory-1];
                app.edit25.Limits = [app.edit24.Value-1 NStory];
            elseif NStory==2
                app.edit21.Limits = [1 2];
                app.edit24.Limits = [1 2];
                app.edit25.Limits = [1 2];
            else
                app.edit24.Enable = 'off';
                app.edit25.Enable = 'off';
            end

            if PM_Option==1
                app.checkbox22.Value = 1;
                app.checkbox22.Enable = 'off';
            end

            if Analysisstatus~=1
                app.edit25.Value = NStory;
                app.edit24.Value = 1;
                app.edit25.Value = min(3,NStory);
                if FrameType==1
                    app.checkbox29.Enable = 'off';
                    app.checkbox31.Enable = 'off';
                else
                    app.checkbox31.Value = 1;
                    app.checkbox31.Enable = 'on';
                end
            else
                app.Typepopup.Value     = num2str(Type);
                app.checkbox3.Value     = Animation;
                app.edit01.Value        = AnimSF;
                app.edit02.Value        = AnimX;
                app.edit03.Value        = AnimY;
                app.checkbox4.Value     = ShowOpenseesStatus;
                app.ShowScopeCB.Value    = ShowScope;
                app.edit00.Value        = MaxRunTime;
                app.uncertaintyCB.Value   = Uncertainty;

                app.edit21.Value        = ModePO;
                app.edit22.Value        = DriftPO*100;

                app.SApopup.Value       = num2str(SA_metric);
                app.edit9.Value         = SA_Step;
                app.edit16.Value        = SAstepmin;
                app.edit6.Value         = T1;
                app.edit18.Value        = SF;
                app.edit19.Value        = TargetSA;
                app.edit20.Value        = CollapseSDR;
                app.edit28.Value        = IDAslopeLimit;
                app.checkbox44.Value    = AdaptiveTimeStep;

                app.edit24.Value        = DampModeI;
                app.edit25.Value        = DampModeJ;
                app.edit26.Value        = zeta*100;

                if exist('zetaSA'); app.edit262.Value       = zetaSA*100; end

                if exist('GMFolderPath','var')~=0 &&  exist(GMFolderPath,'dir')~=0
                    app.editGM.Value = GMFolderPath;
                end

                if exist('coeff_T_lower')
                    app.coeff_T_lower.Value = coeff_T_lower;
                    app.coeff_T_upper.Value = coeff_T_upper;
                end

                if Parallel==1
                    app.CBparallel.Value = Parallel;
                    app.nCores.Value = nCores;
                else
                    app.nCores.Value=maxWorkers;
                end

                if Uncertainty ==1
                    app.editUncertainty.FontColor = [0.0 1.0 0.0];
                    app.editUncertainty.Value     = 'Standard deviations are defined!';
                end


                if isstruct(GM)
                    app.inspectGMButton.Enable = 'on';
                end

                app.edit7.Value  = GM_Start;
                app.edit8.Value  = GM_Last;
                app.edit30.Value = TFreeVibration;
                app.edit31.Value = dtstep;

                %app.checkbox19.Value = Recorders.Time;
                app.checkbox20.Value = Recorders.Disp;
                %app.checkbox21.Value = Recorders.SDR;
                app.checkbox22.Value = Recorders.Column;
                app.checkbox23.Value = Recorders.ColSpring;
                app.checkbox24.Value = Recorders.Beam;
                app.checkbox25.Value = Recorders.BeamSpring;
                app.checkbox26.Value = Recorders.PZ;
                %app.checkbox27.Value = Recorders.RFA;
                app.checkbox28.Value = Recorders.FloorLink;
                app.checkbox29.Value = Recorders.Brace;
                app.checkbox31.Value = Recorders.CGP;
                app.checkbox32.Value = Recorders.Support;
                app.checkbox33.Value = Recorders.RFV;
                app.checkbox34.Value = Recorders.EigenModes;
                app.checkbox35.Value = Recorders.EigenVectors;
                app.checkbox36.Value = Recorders.EGFconnection;
                app.checkbox30.Value = Delete_Flag;


            end

            Typepopup_Callback(app);
            checkbox3ValueChanged(app);
            uncertaintyCBValueChanged(app);
            CBparallelValueChanged(app);

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global  ProjectName ProjectPath

            load(strcat(ProjectPath,ProjectName));

            Type               = str2double(app.Typepopup.Value);
            Animation          = app.checkbox3.Value;
            AnimSF             = app.edit01.Value;
            AnimX              = app.edit02.Value;
            AnimY              = app.edit03.Value;
            ShowOpenseesStatus = app.checkbox4.Value;
            MaxRunTime         = app.edit00.Value;
            Uncertainty        = app.uncertaintyCB.Value;
            Parallel           = app.CBparallel.Value;
            nCores             = app.nCores.Value;

            if Uncertainty==0; nRealizations=1; end

            switch Type
                case 1
                    EV=1; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=0; TTH=0;
                case 2
                    EV=0; PO=1; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=0; TTH=0;
                case 3
                    EV=0; PO=0; IDA=1; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=1; ELF=0; CDPO=0; TTH=0;
                case 4
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=1; Dynamic_TargetSA=0; EQ=1; ELF=0; CDPO=0; TTH=0;
                case 5
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=1; EQ=1; ELF=0; CDPO=0; TTH=0;
                case 6
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=1; CDPO=0; TTH=0;
                case 7
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=1; TTH=0;
                case 8
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=0; TTH=1;
            end

            if EQ==1 && exist('GMFolderPath','var')==0 %|| exist(GMFolderPath,'dir')==0)
                errordlg('Please define the ground motion data first!');
                return
            end

            if Uncertainty==1 && Sigmastatus==0
                errordlg('Modeling uncertainty is seleceted but standard devaiations were not defined!');
                return
            end

            Recorders.Time          = app.checkbox19.Value;
            Recorders.Disp          = app.checkbox20.Value;
            Recorders.SDR           = app.checkbox21.Value;
            Recorders.Column        = app.checkbox22.Value;
            Recorders.ColSpring     = app.checkbox23.Value;
            Recorders.Beam          = app.checkbox24.Value;
            Recorders.BeamSpring    = app.checkbox25.Value;
            Recorders.PZ            = app.checkbox26.Value;
            Recorders.RFA           = app.checkbox27.Value;
            Recorders.FloorLink     = app.checkbox28.Value;
            Recorders.Brace         = app.checkbox29.Value;
            Recorders.CGP           = app.checkbox31.Value;
            Recorders.Support       = app.checkbox32.Value;
            Recorders.RFV           = app.checkbox33.Value;
            Recorders.EigenModes    = app.checkbox34.Value;
            Recorders.EigenVectors  = app.checkbox35.Value;
            Recorders.EGFconnection = app.checkbox36.Value;
            Delete_Flag             = app.checkbox30.Value;

            Filename.Disp       = 'Disp';
            Filename.SDR        = 'SDR';
            Filename.Column     = 'Column';
            Filename.ColSpring  = 'ColSpring';
            Filename.Beam       = 'Beam';
            Filename.BeamSpring = 'BeamSpring';
            Filename.PZ         = 'PZ';
            Filename.RFA        = 'RFA';
            Filename.RFV        = 'RFV';
            Filename.FloorLink  = 'FloorLink';
            Filename.Brace      = 'Brace';
            Filename.CGP        = 'CGP';
            Filename.Support    = 'Support';
            Filename.EGFconnection  = 'EGFconnection';

            ModePO  = app.edit21.Value;
            DriftPO = app.edit22.Value/100;

            SA_metric       = str2double(app.SApopup.Value);
            zetaSA          = app.edit262.Value/100;
            T1              = app.edit6.Value;
            coeff_T_lower   = app.coeff_T_lower.Value;
            coeff_T_upper   = app.coeff_T_upper.Value;
            SA_Step         = app.edit9.Value;
            SAstepmin       = app.edit16.Value;
            SF              = app.edit18.Value;
            TargetSA        = app.edit19.Value;
            CollapseSDR     = app.edit20.Value;
            IDAslopeLimit   = app.edit28.Value;
            AdaptiveTimeStep= app.checkbox44.Value;
            ShowScope       = app.ShowScopeCB.Value;

            DampModeI       = app.edit24.Value;
            DampModeJ       = app.edit25.Value;
            zeta            = app.edit26.Value/100;

            GM_Start        = app.edit7.Value;
            GM_Last         = app.edit8.Value;
            TFreeVibration  = app.edit30.Value;
            dtstep          = app.edit31.Value;

            dinundation     = app.dinundation.Value;
            Drag_Coeff      = app.ro_water.Value;
            ro_water        = app.ro_water.Value;
            CDPOpattern     = app.CDPOpattern.Value;

            if (IDA==1 || Dynamic_TargetSA ==1 || Dynamic_TargetSF==1) && isinf(T1)==1
                errordlg('Period T1 value (used for GM scaling) is not set properly!');
                return
            end

            if IDA==1 || Dynamic_TargetSA ==1 || Dynamic_TargetSF==1
                for GM_No = GM_Start : GM_Last
                    %% GET IM FOR SCALING
                    [Sa] = Get_IM (T1, GM.acc{:,GM_No}, GM.dt{:,GM_No}, g, zetaSA, SA_metric, coeff_T_lower, coeff_T_upper);
                    GM.sa{:,GM_No}=Sa;
                end
            clear GM_No;
            end

            ANALYSIS = struct('IDA',IDA,'Dynamic_TargetSA',Dynamic_TargetSA,'Dynamic_TargetSF',Dynamic_TargetSF,'PO',PO,'EV',EV,'ELF',ELF,'CDPO',CDPO,'TTH',TTH,'Filename',Filename,'Recorders',Recorders,'Delete_Flag',Delete_Flag,'EQ',EQ,'Uncertainty',Uncertainty,'Animation',Animation,'AnimSF',AnimSF,'AnimX',AnimX,'AnimY',AnimY,'ShowOpenseesStatus',ShowOpenseesStatus,'MaxRunTime',MaxRunTime,'ModePO',ModePO,'DriftPO',DriftPO,'SA_metric',SA_metric,'zetaSA',zetaSA,'T1',T1,'coeff_T_lower',coeff_T_lower,'coeff_T_upper',coeff_T_upper,'SA_Step',SA_Step,'SAstepmin',SAstepmin,'SF',SF,'TargetSA',TargetSA,'CollapseSDR',CollapseSDR,'IDAslopeLimit',IDAslopeLimit,'AdaptiveTimeStep',AdaptiveTimeStep,'ShowScope',ShowScope,'DampModeI',DampModeI,'DampModeJ',DampModeJ,'zeta',zeta,'GM_Start',GM_Start,'GM_Last',GM_Last ,'TFreeVibration',TFreeVibration,'dtstep',dtstep,'dinundation',dinundation,'Drag_Coeff',Drag_Coeff,'ro_water',ro_water,'CDPOpattern',CDPOpattern,'Parallel',Parallel,'nCores',nCores);

            Analysisstatus = 1;

            save(strcat(ProjectPath,ProjectName), '-regexp', '^(?!(event|app)$).')

            app.MainApp.status2.BackgroundColor = 'g';
            app.MainApp.status2.FontColor       = 'k';
            if     IDA==1;              app.MainApp.status2.Text=['Dynamic: IDA'];
            elseif Dynamic_TargetSA==1; app.MainApp.status2.Text=['Dynamic: Target Sa'];
            elseif Dynamic_TargetSF==1; app.MainApp.status2.Text=['Dynamic: Target SF'];
            elseif PO==1;               app.MainApp.status2.Text=['Pushover Analysis'];
            elseif EV==1;               app.MainApp.status2.Text=['Eigenvalue Analysis'];
            elseif ELF==1;              app.MainApp.status2.Text=['ELF Analysis'];
            elseif CDPO==1;             app.MainApp.status2.Text=['CDPO Analysis'];
            elseif TTH==1;              app.MainApp.status2.Text=['TTH Analysis'];           end

            app.MainApp.status2b.BackgroundColor = 'g';
            app.MainApp.RunModelButton.Enable    = 'on';

            app.MainApp.ProgressText.FontColor='g';
            app.MainApp.ProgressText.Text=('Analysis data defined'); drawnow;

            figure1CloseRequest(app);
        end

        % Value changed function: Typepopup
        function Typepopup_Callback(app, event)

            Type =str2double(app.Typepopup.Value);
            switch Type
                case 1
                    app.Image_Eigen.Enable='on';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='off';
                    app.edit6.Enable='off';
                    app.edit7.Enable='off';
                    app.edit8.Enable='off';
                    app.edit9.Enable='off';
                    app.edit16.Enable='off';
                    app.edit18.Enable='off';
                    app.edit19.Enable='off';
                    app.edit20.Enable='off';
                    app.edit21.Enable='off';
                    app.edit22.Enable='off';
                    app.edit24.Enable='off';
                    app.edit25.Enable='off';
                    app.edit26.Enable='off';
                    app.edit262.Enable='off';
                    app.edit28.Enable='off';
                    app.edit30.Enable='off';
                    app.edit31.Enable='off';
                    app.GMbutton.Enable='off';
                    app.checkbox44.Enable='off';
                    app.ShowScopeCB.Enable='off';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=0;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'off';
                    app.checkbox20.Value = 0;
                    app.CBparallel.Enable = 'off';
                    app.CBparallel.Value = 0;
                    app.nCores.Enable= 'off';

                case 2
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='on';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='off';
                    app.edit6.Enable='off';
                    app.edit7.Enable='off';
                    app.edit8.Enable='off';
                    app.edit9.Enable='off';
                    app.edit16.Enable='off';
                    app.edit18.Enable='off';   app.text20.Enable='off';
                    app.edit19.Enable='off';   app.text27.Enable='off'; app.text6_3.Enable='off';
                    app.edit20.Enable='off';
                    app.edit21.Enable='on';
                    app.edit22.Enable='on';
                    app.edit24.Enable='off';
                    app.edit25.Enable='off';
                    app.edit26.Enable='off';
                    app.edit262.Enable='off';
                    app.edit28.Enable='off';
                    app.edit30.Enable='off';
                    app.edit31.Enable='off';
                    app.GMbutton.Enable='off';
                    app.checkbox44.Enable='off';
                    app.ShowScopeCB.Enable='off';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=0;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'off';
                    app.checkbox20.Value = 1;
                    app.CBparallel.Enable = 'off';
                    app.CBparallel.Value = 0;
                    app.nCores.Enable= 'off';
                case 3
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='on';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='on';
                    app.edit6.Enable='on';
                    app.edit7.Enable='on';
                    app.edit8.Enable='on';
                    app.edit9.Enable='on';
                    app.edit16.Enable='on';
                    app.edit18.Enable='off';   app.text20.Enable='off';
                    app.edit19.Enable='off';   app.text27.Enable='off'; app.text6_3.Enable='off';
                    app.edit20.Enable='on';
                    app.edit21.Enable='off';
                    app.edit22.Enable='off';
                    app.edit24.Enable='on';
                    app.edit25.Enable='on';
                    app.edit26.Enable='on';
                    app.edit262.Enable='on';
                    app.edit28.Enable='on';
                    app.edit30.Enable='on';
                    app.edit31.Enable='on';
                    app.GMbutton.Enable='on';
                    app.checkbox44.Enable='on';
                    app.ShowScopeCB.Enable='on';
                    app.checkbox30.Enable='on';
                    app.checkbox30.Value=1;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=1;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'on';
                    app.edit30.Value=0;
                    app.CBparallel.Enable = 'off';
                    app.CBparallel.Value = 0;
                    app.nCores.Enable= 'off';

                    app.uncertaintyCB.Value=0;
                    uncertaintyCBValueChanged(app);
                case 4
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='on';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='on';
                    app.editUncertainty.Enable='on';
                    app.uncertaintyCB.Enable = 'on';
                    app.SApopup.Enable='on';
                    app.edit6.Enable='on';
                    app.edit7.Enable='on';
                    app.edit8.Enable='on';
                    app.edit9.Enable='off';
                    app.edit16.Enable='off';
                    app.edit18.Enable='on';  app.text20.Enable='on';
                    app.edit19.Enable='off'; app.text27.Enable='off';  app.text6_3.Enable='off';
                    app.edit20.Enable='on';  
                    app.edit21.Enable='off';
                    app.edit22.Enable='off';
                    app.edit24.Enable='on';
                    app.edit25.Enable='on';
                    app.edit26.Enable='on';
                    app.edit28.Enable='off';
                    app.edit30.Enable='on';
                    app.edit31.Enable='on';
                    app.GMbutton.Enable='on';
                    app.checkbox44.Enable='off';
                    app.ShowScopeCB.Enable='on';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=1;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'on';
                    app.edit30.Value=10;
                    app.CBparallel.Enable = 'on';
                    app.nCores.Enable= 'on';
                case 5
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='on';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='on';
                    app.editUncertainty.Enable='on';
                    app.uncertaintyCB.Enable = 'on';
                    app.SApopup.Enable='on';
                    app.edit6.Enable='on';
                    app.edit7.Enable='on';
                    app.edit8.Enable='on';
                    app.edit9.Enable='off';
                    app.edit16.Enable='off';
                    app.edit18.Enable='off'; app.text20.Enable='off';
                    app.edit19.Enable='on';  app.text27.Enable='on'; app.text6_3.Enable='on';
                    app.edit20.Enable='on';
                    app.edit21.Enable='off';
                    app.edit22.Enable='off';
                    app.edit24.Enable='on';
                    app.edit25.Enable='on';
                    app.edit26.Enable='on';
                    app.edit28.Enable='off';
                    app.edit30.Enable='on';
                    app.edit31.Enable='on';
                    app.GMbutton.Enable='on';
                    app.checkbox44.Enable='off';
                    app.ShowScopeCB.Enable='on';
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=1;
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'on';
                    app.edit30.Value=10;
                    app.CBparallel.Enable = 'on';
                    app.nCores.Enable= 'on';
                case 6
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='on';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='off';
                    app.edit6.Enable='off';
                    app.edit7.Enable='off';
                    app.edit8.Enable='off';
                    app.edit9.Enable='off';
                    app.edit16.Enable='off';
                    app.edit18.Enable='off';   app.text20.Enable='off';
                    app.edit19.Enable='off';   app.text27.Enable='off'; app.text6_3.Enable='off';
                    app.edit20.Enable='off';
                    app.edit21.Enable='off';
                    app.edit22.Enable='off';
                    app.edit24.Enable='off';
                    app.edit25.Enable='off';
                    app.edit26.Enable='off';
                    app.edit28.Enable='off';
                    app.edit30.Enable='off';
                    app.edit31.Enable='off';
                    app.GMbutton.Enable='off';
                    app.checkbox44.Enable='off';
                    app.ShowScopeCB.Enable='off';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=0;
                    app.editELF.Enable='on';
                    app.ELFbutton.Enable='on';
                    app.checkbox20.Enable = 'off';
                    app.checkbox20.Value = 1;
                    app.CBparallel.Enable = 'off';
                    app.CBparallel.Value = 0;
                    app.nCores.Enable= 'off';
                case 7
                    app.Typepopup.Value = "1";
                    Typepopup_Callback(app);
                case 8
                    app.Typepopup.Value = "1";
                    Typepopup_Callback(app);
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible='off';
            delete(app)
        end

        % Button pushed function: GMbutton
        function GMbuttonButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath

            try
                GMFolderPath= uigetdir;

                app.editGM.FontColor = [1.0 1.0 0.0];
                app.editGM.Value     = 'Please Wait...'; drawnow;

                [GMFolderPath, GMFolderName, GM, nGM]=Import_GM_Data(GMFolderPath);
                app.editGM.FontColor = [0.0 1.0 0.0];
                app.editGM.Value     = GMFolderPath;
                if nGM>1
                    app.edit7.Limits     = [1 nGM];
                    app.edit8.Limits     = [1 nGM];
                end
                app.edit8.Value      = nGM;

                save (strcat(ProjectPath,ProjectName),'GMFolderPath','GMFolderName','GM','-append');

                app.inspectGMButton.Enable='on';

            catch ME
                cd (MainDirectory)
                app.editGM.FontColor = [1.0 0.0 0.0];
                app.editGM.Value     = 'Error importing GM data!'; drawnow;
            end


        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            str{1}='This time will be used to terminate the analysis.';
            str{2}='This is generally improtant in cases were a convergence state cannot be reached.';
            str{3}='For dynamic analysis, this time represents the maximum allowed analysis time for a single GM record.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon4
        function TipIcon4ButtonPushed(app, event)
            str{1}='This is an additional criteria that will be used to track the collapse point.';
            str{2}='Collapse is flagged if the slope of the IDA curve (SDR vs Intensity) exceeds the specified value.';
            str{3}='A slope value of 0.4 is recommended. This corrosponds to an increase of 0.04rad per 0.1g';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Value changed function: checkbox44
        function checkbox44ValueChanged(app, event)
            if app.checkbox44.Value ==1
                app.text9.Text='Initial Increment';
            else
                app.text9.Text='Constant Increment';
            end

        end

        % Button pushed function: ELFbutton
        function ELFbuttonButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath

            load(strcat(ProjectPath,ProjectName),'NStory')

            [ELFFileName,ELFFilePath] = uigetfile('*.txt','Select the .txt file containing lateral force profile');

            try
                app.editELF.FontColor = [0.0 0.0 0.0];
                app.editELF.Value     = ('Please wait ...'); drawnow;

                % Read ELF Force Data
                cd(ELFFilePath);

                ELF_Profile=importdata(ELFFileName); % Read the file data

                % If the file "GM Data.txt" exits
                if size(ELF_Profile,1)~=NStory
                    d=errordlg(['The number of data rows in the file shall be equal to',num2str(NStory), '!'],'Error','modal');
                    waitfor(d);
                    return
                end

                save(strcat(ProjectPath,ProjectName),'ELFFileName','ELFFilePath','ELF_Profile','-append');

                cd (MainDirectory);
                app.editELF.FontColor = [0.47 0.67 0.19];
                app.editELF.Value     = 'Import successful!'; drawnow;
            catch ME
                cd (MainDirectory);
                write_Error(ME);
                app.editELF.FontColor = [1 0. 0.];
                app.editELF.Value     = 'Error importing file. See Error file!'; drawnow;
            end
        end

        % Value changed function: checkbox3
        function checkbox3ValueChanged(app, event)
            if app.checkbox3.Value==1
                app.edit01.Enable='on';
                app.edit02.Enable='on';
                app.edit03.Enable='on';
            else
                app.edit01.Enable='off';
                app.edit02.Enable='off';
                app.edit03.Enable='off';
            end
        end

        % Button pushed function: TipIcon6
        function TipIcon6ButtonPushed(app, event)
            str{1}='Include the uncertainty associated with the numerical modeling parameters.';
            str{2}='This can be used as part of a Monte Carlo simulation scheme involving multiple numerical realization for each ground motion record.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: Sigmabutton
        function SigmabuttonButtonPushed(app, event)
            global  ProjectName ProjectPath

            app.editUncertainty.FontColor = [1.0 1.0 0.0];
            app.editUncertainty.Value = 'waiting for user input...';
            waitfor(Uncertainty_App(app));

            load(strcat(ProjectPath,ProjectName),'Sigmastatus')

            if Sigmastatus==0
                app.editUncertainty.FontColor = [1.0 0.0 0.0];
                app.editUncertainty.Value     = 'Standard deviations are not defined!';
            else
                app.editUncertainty.FontColor = [0.0 1.0 0.0];
                app.editUncertainty.Value     = 'Standard deviations are defined!';
            end

        end

        % Value changed function: uncertaintyCB
        function uncertaintyCBValueChanged(app, event)
            if app.uncertaintyCB.Value==0
                app.Sigmabutton.Enable      = 'off';
                app.editUncertainty.Enable  = 'off';
                app.CBparallel.Enable  = 'on';
            else
                app.Sigmabutton.Enable      = 'on';
                app.editUncertainty.Enable  = 'on';
                app.CBparallel.Enable  = 'off';
                app.CBparallel.Value  = 0;
            end

              Type =str2double(app.Typepopup.Value);

            if Type ==4 && Type ==5
                app.CBparallel.Value=0;
                app.CBparallel.Enable='on';                
            else
                app.CBparallel.Value=0;
                app.CBparallel.Enable='off';
            end
            

            CBparallelValueChanged(app);
        end

        % Button pushed function: Tsunamibutton
        function TsunamibuttonButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath

            load(strcat(ProjectPath,ProjectName),'HBuilding','Elevation','NStory','HStory','TD_MF','Units')

            [TTHFileName,TTHFilePath] = uigetfile('*.txt','Select the .txt file containing tsunami time history');

            try
                app.editTsunami.FontColor = [0.0 0.0 0.0];
                app.editTsunami.Value     = ('Please wait ...'); drawnow;

                % Read ELF Force Data
                cd(TTHFilePath);

                TTH_Data=importdata(TTHFileName); % Read the file data

                % If the file "GM Data.txt" exits
                if size(TTH_Data,2)~=3
                    d=errordlg(['The number of data columns in the file shall be equal to 3!'],'Error','modal');
                    waitfor(d);
                    return
                end

                Drag_Coeff = app.ro_water.Value;
                ro_water   = app.ro_water.Value;

                [TTHdt, TTHpoints, F, Hinundation] = get_Tsunami_Force(TTH_Data, TD_MF, ro_water, Drag_Coeff, Units);

                [Floor_F]=get_Tsunami_ForceTH_Breakdown(NStory,HStory, Elevation, F, Hinundation,Units);

                Floor_F(isnan(Floor_F))=0;

                save(strcat(ProjectPath,ProjectName),'TTHFileName','TTHFilePath','TTH_Data','TTHdt','Floor_F', 'TTHpoints','-append');

                app.editTsunami.FontColor = [0.47 0.67 0.19];
                app.editTsunami.Value     = 'Import successful!'; drawnow;


            catch ME
                cd (MainDirectory);
                write_Error(ME);
                app.editTsunami.FontColor = [1 0. 0.];
                app.editTsunami.Value     = 'Error importing file. See Error file!'; drawnow;
            end
        end

        % Value changed function: SApopup
        function SApopupValueChanged(app, event)
            if app.SApopup.Value ==1
                app.coeff_T_lower.Enable='off';
                app.coeff_T_upper.Enable='off';
            else
                app.coeff_T_lower.Enable='on';
                app.coeff_T_upper.Enable='on';
            end

        end

        % Button pushed function: inspectGMButton
        function inspectGMButtonPushed(app, event)
            Visualize_GM_App();
        end

        % Value changed function: CBparallel
        function CBparallelValueChanged(app, event)
            value = app.CBparallel.Value;
            if value ==1
                app.ShowScopeCB.Value=0;
                app.ShowScopeCB.Enable='off';
                app.nCores.Enable='on';
                cluster = parcluster('local');
                maxWorkers = cluster.NumWorkers;
                app.nCores.Limits=[2 maxWorkers];
                app.nCores.Value=maxWorkers;
            else
                app.ShowScopeCB.Value=0;
                app.ShowScopeCB.Enable='on';
                app.nCores.Enable='off';
                cluster = parcluster('local');
                maxWorkers = cluster.NumWorkers;
                app.nCores.Limits=[0 maxWorkers];
                app.nCores.Value=maxWorkers;
            end

            Type =str2double(app.Typepopup.Value);

            if Type ~=3 && Type ~=4 && Type ~=5
                app.ShowScopeCB.Value=0;
                app.ShowScopeCB.Enable='off';
            else
                app.ShowScopeCB.Enable='on';                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Analysis Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'Analyzebutton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.AnalysisOptionsTab = uitab(app.TabGroup);
            app.AnalysisOptionsTab.Title = 'Analysis Options';
            app.Image_PO = uiimage(app.AnalysisOptionsTab);
            app.Image_PO.ImageSource = fullfile(pathToMLAPP, 'PO.png');
            app.Image_EQ_SF = uiimage(app.AnalysisOptionsTab);
            app.Image_EQ_SF.ImageSource = fullfile(pathToMLAPP, 'EQ_SF.png');
            app.Image_EQ_Sa = uiimage(app.AnalysisOptionsTab);
            app.Image_EQ_Sa.ImageSource = fullfile(pathToMLAPP, 'EQ_Sa.png');
            app.Image_IDA = uiimage(app.AnalysisOptionsTab);
            app.Image_IDA.ImageSource = fullfile(pathToMLAPP, 'IDA.png');
            app.text30_3 = uilabel(app.AnalysisOptionsTab);
            app.text30_3.Tag = 'text30';
            app.text30_3.Text = 'Analysis Type';
            app.Typepopup = uidropdown(app.AnalysisOptionsTab);
            app.Typepopup.Items = {'Eigenvalue Analysis', 'Static Pushover Analysis', 'Incremental Dynamic Analysis', 'Dynamic: Target Scale Factor', 'Dynamic: Target Intesity', 'Equivelant Lateral Force Analysis', 'Tsunami Constant Depth Pushover', 'Tsunami Time History'};
            app.Typepopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Typepopup.ValueChangedFcn = createCallbackFcn(app, @Typepopup_Callback, true);
            app.Typepopup.Tag = 'popupmenu1';
            app.Typepopup.Value = '1';
            app.Image_Eigen = uiimage(app.AnalysisOptionsTab);
            app.Image_Eigen.ImageSource = fullfile(pathToMLAPP, 'Eigen.png');
            app.Image_ELF = uiimage(app.AnalysisOptionsTab);
            app.Image_ELF.ImageSource = fullfile(pathToMLAPP, 'ELF.png');
            app.RunOptionsTab = uitab(app.TabGroup);
            app.RunOptionsTab.Title = 'Run Options';
            app.checkbox3 = uicheckbox(app.RunOptionsTab);
            app.checkbox3.ValueChangedFcn = createCallbackFcn(app, @checkbox3ValueChanged, true);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Show animation';
            app.checkbox4 = uicheckbox(app.RunOptionsTab);
            app.checkbox4.Tag = 'checkbox4';
            app.checkbox4.Text = 'Show OpenSEES status';
            app.text31_2 = uilabel(app.RunOptionsTab);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'Maximum runtime';
            app.text32_2 = uilabel(app.RunOptionsTab);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'minutes';
            app.edit00 = uieditfield(app.RunOptionsTab, 'numeric');
            app.edit00.Limits = [0.1 Inf];
            app.edit00.ValueDisplayFormat = '%.1f';
            app.edit00.Value = 10;
            app.TipIcon5 = uibutton(app.RunOptionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.ShowScopeCB = uicheckbox(app.RunOptionsTab);
            app.ShowScopeCB.Tag = 'checkbox22';
            app.ShowScopeCB.Tooltip = {'Plots the maximum SDR and PFA values for each ground motion record during analysis.'};
            app.ShowScopeCB.Text = 'Show scope';
            app.ShowScopeCB.Value = true;
            app.edit01 = uieditfield(app.RunOptionsTab, 'numeric');
            app.edit01.Limits = [0.1 50];
            app.edit01.ValueDisplayFormat = '%.1f';
            app.edit01.Value = 5;
            app.edit02 = uieditfield(app.RunOptionsTab, 'numeric');
            app.edit02.Limits = [50 3000];
            app.edit02.ValueDisplayFormat = '%.0f';
            app.edit02.Value = 1000;
            app.edit03 = uieditfield(app.RunOptionsTab, 'numeric');
            app.edit03.Limits = [50 3000];
            app.edit03.ValueDisplayFormat = '%.0f';
            app.edit03.Value = 750;
            app.text32_3 = uilabel(app.RunOptionsTab);
            app.text32_3.Tag = 'text32';
            app.text32_3.Text = 'scale';
            app.text32_4 = uilabel(app.RunOptionsTab);
            app.text32_4.Tag = 'text32';
            app.text32_4.Text = 'x';
            app.text32_5 = uilabel(app.RunOptionsTab);
            app.text32_5.Tag = 'text32';
            app.text32_5.Text = 'window size';
            app.uncertaintyCB = uicheckbox(app.RunOptionsTab);
            app.uncertaintyCB.ValueChangedFcn = createCallbackFcn(app, @uncertaintyCBValueChanged, true);
            app.uncertaintyCB.Tag = 'checkbox22';
            app.uncertaintyCB.Text = 'Include modeling uncertainty';
            app.Sigmabutton = uibutton(app.RunOptionsTab, 'push');
            app.Sigmabutton.ButtonPushedFcn = createCallbackFcn(app, @SigmabuttonButtonPushed, true);
            app.Sigmabutton.Tag = 'Savebutton';
            app.Sigmabutton.Text = 'define';
            app.editUncertainty = uieditfield(app.RunOptionsTab, 'text');
            app.editUncertainty.Editable = 'off';
            app.TipIcon6 = uibutton(app.RunOptionsTab, 'push');
            app.TipIcon6.ButtonPushedFcn = createCallbackFcn(app, @TipIcon6ButtonPushed, true);
            app.TipIcon6.Text = '?';
            app.CBparallel = uicheckbox(app.RunOptionsTab);
            app.CBparallel.ValueChangedFcn = createCallbackFcn(app, @CBparallelValueChanged, true);
            app.CBparallel.Tag = 'checkbox4';
            app.CBparallel.Tooltip = {'This option used parallel computing for dynamic analysis runs. This -beta- option might not work on some PCs.'};
            app.CBparallel.Text = 'Run using parallel cores';
            app.nCores = uieditfield(app.RunOptionsTab, 'numeric');
            app.nCores.Limits = [0.1 Inf];
            app.nCores.ValueDisplayFormat = '%.0f';
            app.nCores.Tooltip = {'number of cores to use'};
            app.nCores.Value = 10;
            app.text69_5 = uilabel(app.RunOptionsTab);
            app.text69_5.Tag = 'text6';
            app.text69_5.Text = '*beta (new)';
            app.PushoverELFParametersTab = uitab(app.TabGroup);
            app.PushoverELFParametersTab.Title = 'Pushover/ELF Parameters';
            app.text30 = uilabel(app.PushoverELFParametersTab);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Pushover pattern based on Mode';
            app.text31 = uilabel(app.PushoverELFParametersTab);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Target roof drift level';
            app.text32 = uilabel(app.PushoverELFParametersTab);
            app.text32.Tag = 'text32';
            app.text32.Tooltip = {'Roof drift ratio as a percentage of total building height.'};
            app.text32.Text = '%';
            app.edit21 = uieditfield(app.PushoverELFParametersTab, 'numeric');
            app.edit21.Limits = [1 Inf];
            app.edit21.ValueDisplayFormat = '%.0f';
            app.edit21.Value = 1;
            app.edit22 = uieditfield(app.PushoverELFParametersTab, 'numeric');
            app.edit22.Limits = [0.1 Inf];
            app.edit22.ValueDisplayFormat = '%.1f';
            app.edit22.Tooltip = {'Roof drift ratio as a percentage of total building height.'};
            app.edit22.Value = 10;
            app.text28_2 = uilabel(app.PushoverELFParametersTab);
            app.text28_2.Tag = 'text28';
            app.text28_2.Text = 'ELF profile';
            app.ELFbutton = uibutton(app.PushoverELFParametersTab, 'push');
            app.ELFbutton.ButtonPushedFcn = createCallbackFcn(app, @ELFbuttonButtonPushed, true);
            app.ELFbutton.Tag = 'Savebutton';
            app.ELFbutton.Tooltip = {'Browse for a text file containing a single column of values representing the equivelant lateral forces at each floor.'};
            app.ELFbutton.Text = 'browse';
            app.editELF = uieditfield(app.PushoverELFParametersTab, 'text');
            app.editELF.Editable = 'off';
            app.DynamicParametersTab = uitab(app.TabGroup);
            app.DynamicParametersTab.Title = 'Dynamic Parameters';
            app.text9 = uilabel(app.DynamicParametersTab);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Initial increment';
            app.text16 = uilabel(app.DynamicParametersTab);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Collapse point tolerance';
            app.text6 = uilabel(app.DynamicParametersTab);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Scaling period';
            app.text20 = uilabel(app.DynamicParametersTab);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Scaling factor';
            app.text27 = uilabel(app.DynamicParametersTab);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Target intensity';
            app.SApopup = uidropdown(app.DynamicParametersTab);
            app.SApopup.Items = {'Sa(T1)', 'Sa_avg'};
            app.SApopup.ItemsData = {'1', '2'};
            app.SApopup.ValueChangedFcn = createCallbackFcn(app, @SApopupValueChanged, true);
            app.SApopup.Tag = 'SApopup';
            app.SApopup.Value = '1';
            app.text38 = uilabel(app.DynamicParametersTab);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Intensity measure';
            app.edit9 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit9.Limits = [0.01 Inf];
            app.edit9.ValueDisplayFormat = '%.2f';
            app.edit9.Value = 0.2;
            app.edit6 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit6.Limits = [0.001 Inf];
            app.edit6.ValueDisplayFormat = '%.3f';
            app.edit6.Value = 1;
            app.edit16 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit16.Limits = [0.01 Inf];
            app.edit16.ValueDisplayFormat = '%.2f';
            app.edit16.Value = 0.05;
            app.edit18 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit18.Limits = [0.001 Inf];
            app.edit18.ValueDisplayFormat = '%.3f';
            app.edit18.Value = 1;
            app.edit19 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit19.Limits = [0.001 Inf];
            app.edit19.ValueDisplayFormat = '%.3f';
            app.edit19.Value = 1;
            app.checkbox44 = uicheckbox(app.DynamicParametersTab);
            app.checkbox44.ValueChangedFcn = createCallbackFcn(app, @checkbox44ValueChanged, true);
            app.checkbox44.Tag = 'checkbox22';
            app.checkbox44.Text = 'Adaptive intensity increment';
            app.checkbox44.Value = true;
            app.text27_2 = uilabel(app.DynamicParametersTab);
            app.text27_2.Tag = 'text27';
            app.text27_2.Text = 'Collapse drift limit';
            app.edit20 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit20.Limits = [0.001 Inf];
            app.edit20.ValueDisplayFormat = '%.3f';
            app.edit20.Value = 0.15;
            app.text27_3 = uilabel(app.DynamicParametersTab);
            app.text27_3.Tag = 'text27';
            app.text27_3.Text = 'Maximum IDA curve slope';
            app.edit28 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit28.Limits = [0.001 Inf];
            app.edit28.ValueDisplayFormat = '%.3f';
            app.edit28.Value = 0.5;
            app.TipIcon4 = uibutton(app.DynamicParametersTab, 'push');
            app.TipIcon4.ButtonPushedFcn = createCallbackFcn(app, @TipIcon4ButtonPushed, true);
            app.TipIcon4.Text = '?';
            app.text6_2 = uilabel(app.DynamicParametersTab);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = '[sec]';
            app.text6_3 = uilabel(app.DynamicParametersTab);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = '[g]';
            app.text6_4 = uilabel(app.DynamicParametersTab);
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = '[g]';
            app.text6_5 = uilabel(app.DynamicParametersTab);
            app.text6_5.Tag = 'text6';
            app.text6_5.Text = '[rad]';
            app.text6_6 = uilabel(app.DynamicParametersTab);
            app.text6_6.Tag = 'text6';
            app.text6_6.Text = '[rad/g]';
            app.text6_7 = uilabel(app.DynamicParametersTab);
            app.text6_7.Tag = 'text6';
            app.text6_7.Text = '[g]';
            app.text39_4 = uilabel(app.DynamicParametersTab);
            app.text39_4.Tag = 'text39';
            app.text39_4.Text = ', ξ =';
            app.text39_5 = uilabel(app.DynamicParametersTab);
            app.text39_5.Tag = 'text39';
            app.text39_5.Text = '%';
            app.edit262 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.edit262.Limits = [0.001 Inf];
            app.edit262.ValueDisplayFormat = '%.1f';
            app.edit262.Value = 5;
            app.text39_6 = uilabel(app.DynamicParametersTab);
            app.text39_6.Tag = 'text39';
            app.text39_6.Text = 'to';
            app.coeff_T_lower = uieditfield(app.DynamicParametersTab, 'numeric');
            app.coeff_T_lower.Limits = [0.001 Inf];
            app.coeff_T_lower.ValueDisplayFormat = '%.1f';
            app.coeff_T_lower.Value = 0.2;
            app.coeff_T_upper = uieditfield(app.DynamicParametersTab, 'numeric');
            app.coeff_T_upper.Limits = [0.001 Inf];
            app.coeff_T_upper.ValueDisplayFormat = '%.1f';
            app.coeff_T_upper.Value = 3;
            app.text39_7 = uilabel(app.DynamicParametersTab);
            app.text39_7.Tag = 'text39';
            app.text39_7.Text = 'T';
            app.text6_9 = uilabel(app.DynamicParametersTab);
            app.text6_9.Tag = 'text6';
            app.text6_9.Text = 'Range =';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.text34 = uilabel(app.DampingTab);
            app.text34.Tag = 'text34';
            app.text34.Tooltip = 'Specify the two periods for the Raleigh damping model';
            app.text34.Text = 'Raleigh periods';
            app.text35 = uilabel(app.DampingTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Damping ratio';
            app.text39 = uilabel(app.DampingTab);
            app.text39.Tag = 'text39';
            app.text39.Text = '%';
            app.edit24 = uieditfield(app.DampingTab, 'numeric');
            app.edit24.Limits = [1 Inf];
            app.edit24.ValueDisplayFormat = '%.0f';
            app.edit24.Value = 1;
            app.edit25 = uieditfield(app.DampingTab, 'numeric');
            app.edit25.Limits = [1 Inf];
            app.edit25.ValueDisplayFormat = '%.0f';
            app.edit25.Value = 1;
            app.text39_2 = uilabel(app.DampingTab);
            app.text39_2.Tag = 'text39';
            app.text39_2.Text = 'and';
            app.edit26 = uieditfield(app.DampingTab, 'numeric');
            app.edit26.Limits = [0 Inf];
            app.edit26.ValueDisplayFormat = '%.1f';
            app.edit26.Value = 2;
            app.GroundMotionsTab = uitab(app.TabGroup);
            app.GroundMotionsTab.Title = 'Ground Motions';
            app.text28 = uilabel(app.GroundMotionsTab);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Ground motion folder';
            app.text7 = uilabel(app.GroundMotionsTab);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Consider GM records #';
            app.text8 = uilabel(app.GroundMotionsTab);
            app.text8.Tag = 'text8';
            app.text8.Text = 'to';
            app.edit7 = uieditfield(app.GroundMotionsTab, 'numeric');
            app.edit7.Limits = [1 Inf];
            app.edit7.ValueDisplayFormat = '%.0f';
            app.edit7.Value = 1;
            app.edit8 = uieditfield(app.GroundMotionsTab, 'numeric');
            app.edit8.Limits = [1 Inf];
            app.edit8.ValueDisplayFormat = '%.0f';
            app.edit8.Value = 1;
            app.text41 = uilabel(app.GroundMotionsTab);
            app.text41.Tag = 'text41';
            app.text41.Tooltip = {''};
            app.text41.Text = 'Free vibration time';
            app.edit30 = uieditfield(app.GroundMotionsTab, 'numeric');
            app.edit30.Limits = [0 Inf];
            app.edit30.ValueDisplayFormat = '%.1f';
            app.edit30.Value = 10;
            app.GMbutton = uibutton(app.GroundMotionsTab, 'push');
            app.GMbutton.ButtonPushedFcn = createCallbackFcn(app, @GMbuttonButtonPushed, true);
            app.GMbutton.Tag = 'Savebutton';
            app.GMbutton.Text = 'browse';
            app.text41_2 = uilabel(app.GroundMotionsTab);
            app.text41_2.Tag = 'text41';
            app.text41_2.Tooltip = {''};
            app.text41_2.Text = 'Analysis time step';
            app.edit31 = uieditfield(app.GroundMotionsTab, 'numeric');
            app.edit31.Limits = [0.001 1];
            app.edit31.ValueDisplayFormat = '%.2f';
            app.edit31.Value = 0.5;
            app.editGM = uieditfield(app.GroundMotionsTab, 'text');
            app.editGM.Editable = 'off';
            app.text6_8 = uilabel(app.GroundMotionsTab);
            app.text6_8.Tag = 'text6';
            app.text6_8.Text = '[sec]';
            app.text41_3 = uilabel(app.GroundMotionsTab);
            app.text41_3.Tag = 'text41';
            app.text41_3.Tooltip = {''};
            app.text41_3.Text = 'x record time step';
            app.inspectGMButton = uibutton(app.GroundMotionsTab, 'push');
            app.inspectGMButton.ButtonPushedFcn = createCallbackFcn(app, @inspectGMButtonPushed, true);
            app.inspectGMButton.Tag = 'BrowseButton';
            app.inspectGMButton.Enable = 'off';
            app.inspectGMButton.Tooltip = {'inspect the imported GM records'};
            app.inspectGMButton.Text = 'Inspect GM';
            app.inspectGMButton.Icon = fullfile(pathToMLAPP, 'scope.png');
            app.RecordersTab = uitab(app.TabGroup);
            app.RecordersTab.Title = 'Recorders';
            app.checkbox19 = uicheckbox(app.RecordersTab);
            app.checkbox19.Tag = 'checkbox19';
            app.checkbox19.Enable = 'off';
            app.checkbox19.Text = 'Time';
            app.checkbox19.Value = true;
            app.checkbox20 = uicheckbox(app.RecordersTab);
            app.checkbox20.Tag = 'checkbox20';
            app.checkbox20.Enable = 'off';
            app.checkbox20.Text = 'Floor Displacement';
            app.checkbox20.Value = true;
            app.checkbox21 = uicheckbox(app.RecordersTab);
            app.checkbox21.Tag = 'checkbox21';
            app.checkbox21.Enable = 'off';
            app.checkbox21.Text = 'Story Drift Ratio';
            app.checkbox21.Value = true;
            app.checkbox22 = uicheckbox(app.RecordersTab);
            app.checkbox22.Tag = 'checkbox22';
            app.checkbox22.Text = 'Column Element Forces';
            app.checkbox22.Value = true;
            app.checkbox23 = uicheckbox(app.RecordersTab);
            app.checkbox23.Tag = 'checkbox23';
            app.checkbox23.Text = 'Column Spring M/R';
            app.checkbox24 = uicheckbox(app.RecordersTab);
            app.checkbox24.Tag = 'checkbox24';
            app.checkbox24.Text = 'Beam Element Forces';
            app.checkbox25 = uicheckbox(app.RecordersTab);
            app.checkbox25.Tag = 'checkbox25';
            app.checkbox25.Text = 'Beam Spring M/R';
            app.checkbox27 = uicheckbox(app.RecordersTab);
            app.checkbox27.Tag = 'checkbox27';
            app.checkbox27.Enable = 'off';
            app.checkbox27.Text = 'Floor Acceleration';
            app.checkbox27.Value = true;
            app.checkbox28 = uicheckbox(app.RecordersTab);
            app.checkbox28.Tag = 'checkbox28';
            app.checkbox28.Text = 'Floor Link F/D';
            app.checkbox26 = uicheckbox(app.RecordersTab);
            app.checkbox26.Tag = 'checkbox26';
            app.checkbox26.Text = 'Panel Zone Spring M/R';
            app.checkbox30 = uicheckbox(app.RecordersTab);
            app.checkbox30.Tag = 'checkbox21';
            app.checkbox30.Tooltip = {'Consider selecting this option when running IDA.'};
            app.checkbox30.Text = 'Delete output data files after finishing the run';
            app.checkbox29 = uicheckbox(app.RecordersTab);
            app.checkbox29.Tag = 'checkbox28';
            app.checkbox29.Text = 'Brace F/D';
            app.checkbox31 = uicheckbox(app.RecordersTab);
            app.checkbox31.Tag = 'checkbox28';
            app.checkbox31.Text = 'Corner Gusset Plate Force';
            app.checkbox32 = uicheckbox(app.RecordersTab);
            app.checkbox32.Tag = 'checkbox28';
            app.checkbox32.Enable = 'off';
            app.checkbox32.Text = 'Support Reaction';
            app.checkbox32.Value = true;
            app.checkbox33 = uicheckbox(app.RecordersTab);
            app.checkbox33.Tag = 'checkbox27';
            app.checkbox33.Text = 'Floor Velocity';
            app.checkbox34 = uicheckbox(app.RecordersTab);
            app.checkbox34.Tag = 'checkbox28';
            app.checkbox34.Enable = 'off';
            app.checkbox34.Text = 'Eigen Modes';
            app.checkbox34.Value = true;
            app.checkbox35 = uicheckbox(app.RecordersTab);
            app.checkbox35.Tag = 'checkbox28';
            app.checkbox35.Enable = 'off';
            app.checkbox35.Text = 'Eigen Vectors';
            app.checkbox35.Value = true;
            app.checkbox36 = uicheckbox(app.RecordersTab);
            app.checkbox36.Tag = 'checkbox26';
            app.checkbox36.Text = 'EGF Connection Spring M/R';
            app.TsunamiPushoverTab = uitab(app.TabGroup);
            app.TsunamiPushoverTab.Title = 'Tsunami Pushover';
            app.text31_3 = uilabel(app.TsunamiPushoverTab);
            app.text31_3.Tag = 'text31';
            app.text31_3.Text = 'Maximum inundation depth';
            app.dinundation = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.dinundation.Limits = [0.1 Inf];
            app.dinundation.ValueDisplayFormat = '%.1f';
            app.dinundation.Tooltip = {''};
            app.dinundation.Value = 10;
            app.text69 = uilabel(app.TsunamiPushoverTab);
            app.text69.Tag = 'text6';
            app.text69.Text = 'm';
            app.text31_4 = uilabel(app.TsunamiPushoverTab);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'Froude number';
            app.FroudeNumber = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.FroudeNumber.Limits = [0 3];
            app.FroudeNumber.ValueDisplayFormat = '%.1f';
            app.FroudeNumber.Tooltip = {''};
            app.FroudeNumber.Value = 1;
            app.CDPOpattern = uidropdown(app.TsunamiPushoverTab);
            app.CDPOpattern.Items = {'Inverted Triangle', 'Constant'};
            app.CDPOpattern.ItemsData = {'1', '2'};
            app.CDPOpattern.Tag = 'SApopup';
            app.CDPOpattern.Value = '1';
            app.text38_2 = uilabel(app.TsunamiPushoverTab);
            app.text38_2.Tag = 'text38';
            app.text38_2.Text = 'Pushover pattern';
            app.text31_5 = uilabel(app.TsunamiPushoverTab);
            app.text31_5.Tag = 'text31';
            app.text31_5.Text = 'Water density';
            app.ro_water = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.ro_water.Limits = [0 Inf];
            app.ro_water.ValueDisplayFormat = '%.1f';
            app.ro_water.Tooltip = {''};
            app.ro_water.Value = 1.2;
            app.text70 = uilabel(app.TsunamiPushoverTab);
            app.text70.Tag = 'text6';
            app.text70.Text = 'ton/m3';
            app.SApopup_3 = uidropdown(app.TsunamiPushoverTab);
            app.SApopup_3.Items = {'ASCE 7', 'Foster et al (2017**)'};
            app.SApopup_3.ItemsData = {'1', '2'};
            app.SApopup_3.Tag = 'SApopup';
            app.SApopup_3.Enable = 'off';
            app.SApopup_3.Value = '1';
            app.text38_3 = uilabel(app.TsunamiPushoverTab);
            app.text38_3.Tag = 'text38';
            app.text38_3.Text = 'Force computation';
            app.text31_6 = uilabel(app.TsunamiPushoverTab);
            app.text31_6.Tag = 'text31';
            app.text31_6.Text = 'Drag Coefficient';
            app.Drag_Coeff = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.Drag_Coeff.Limits = [0 Inf];
            app.Drag_Coeff.ValueDisplayFormat = '%.1f';
            app.Drag_Coeff.Tooltip = {'Roof drift ratio as a percentage of total building height.'};
            app.Drag_Coeff.Value = 1.2;
            app.text69_4 = uilabel(app.TsunamiPushoverTab);
            app.text69_4.Tag = 'text6';
            app.text69_4.Text = '*beta';
            app.TsunamiTimeHistoryTab = uitab(app.TabGroup);
            app.TsunamiTimeHistoryTab.Title = 'Tsunami Time History';
            app.text28_3 = uilabel(app.TsunamiTimeHistoryTab);
            app.text28_3.Tag = 'text28';
            app.text28_3.Text = 'Tsunami data file';
            app.Tsunamibutton = uibutton(app.TsunamiTimeHistoryTab, 'push');
            app.Tsunamibutton.ButtonPushedFcn = createCallbackFcn(app, @TsunamibuttonButtonPushed, true);
            app.Tsunamibutton.Tag = 'Savebutton';
            app.Tsunamibutton.Text = 'browse';
            app.editTsunami = uieditfield(app.TsunamiTimeHistoryTab, 'text');
            app.editTsunami.Editable = 'off';
            app.text69_3 = uilabel(app.TsunamiTimeHistoryTab);
            app.text69_3.Tag = 'text6';
            app.text69_3.Text = '*beta';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Analysis_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end