classdef Build_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        BuildingGeometryTab     matlab.ui.container.Tab
        BraceLayoutpopup        matlab.ui.control.DropDown
        text67                  matlab.ui.control.Label
        showMRFButton           matlab.ui.control.Button
        text31_6                matlab.ui.control.Label
        text31_5                matlab.ui.control.Label
        editExcel               matlab.ui.control.EditField
        nGB                     matlab.ui.control.NumericEditField
        nGC                     matlab.ui.control.NumericEditField
        NBay                    matlab.ui.control.NumericEditField
        NStory                  matlab.ui.control.NumericEditField
        nMRF                    matlab.ui.control.NumericEditField
        TAin2                   matlab.ui.control.NumericEditField
        TAin1                   matlab.ui.control.NumericEditField
        TAex2                   matlab.ui.control.NumericEditField
        TAex1                   matlab.ui.control.NumericEditField
        DIM2                    matlab.ui.control.NumericEditField
        DIM1                    matlab.ui.control.NumericEditField
        text84                  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        text25_2                matlab.ui.control.Label
        text25                  matlab.ui.control.Label
        text32_2                matlab.ui.control.Label
        text42_2                matlab.ui.control.Label
        text65                  matlab.ui.control.Label
        text51                  matlab.ui.control.Label
        text50                  matlab.ui.control.Label
        text49                  matlab.ui.control.Label
        text47                  matlab.ui.control.Label
        text46                  matlab.ui.control.Label
        text45                  matlab.ui.control.Label
        text42                  matlab.ui.control.Label
        text33                  matlab.ui.control.Label
        text32                  matlab.ui.control.Label
        text31                  matlab.ui.control.Label
        SupportsConnectionsTab  matlab.ui.container.Tab
        text314                 matlab.ui.control.Label
        SupportGFSpopup         matlab.ui.control.DropDown
        text35_20               matlab.ui.control.Label
        text35_19               matlab.ui.control.Label
        text35_18               matlab.ui.control.Label
        text311                 matlab.ui.control.Label
        text313                 matlab.ui.control.Label
        Orientationpopup        matlab.ui.control.DropDown
        text312                 matlab.ui.control.Label
        EGFConnectionpopup      matlab.ui.control.DropDown
        k_beambracing           matlab.ui.control.NumericEditField
        text58_2                matlab.ui.control.Label
        text71_2                matlab.ui.control.Label
        TipIcon6                matlab.ui.control.Button
        ConnectionOddpopup      matlab.ui.control.DropDown
        text301                 matlab.ui.control.Label
        text303                 matlab.ui.control.Label
        MidSpanpopup            matlab.ui.control.DropDown
        text30                  matlab.ui.control.Label
        ConnectionEvenpopup     matlab.ui.control.DropDown
        text302                 matlab.ui.control.Label
        TipIcon5                matlab.ui.control.Button
        text31_7                matlab.ui.control.Label
        Splicepopup             matlab.ui.control.DropDown
        FuseF_ProfileCB         matlab.ui.control.CheckBox
        Ks                      matlab.ui.control.NumericEditField
        Fs                      matlab.ui.control.NumericEditField
        c                       matlab.ui.control.NumericEditField
        b                       matlab.ui.control.NumericEditField
        a                       matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
        text31_4                matlab.ui.control.Label
        text75                  matlab.ui.control.Label
        text74                  matlab.ui.control.Label
        text73                  matlab.ui.control.Label
        text72                  matlab.ui.control.Label
        Linkpopup               matlab.ui.control.DropDown
        text304                 matlab.ui.control.Label
        text71                  matlab.ui.control.Label
        text70                  matlab.ui.control.Label
        text69                  matlab.ui.control.Label
        text58                  matlab.ui.control.Label
        text57                  matlab.ui.control.Label
        text56                  matlab.ui.control.Label
        Connectionpopup         matlab.ui.control.DropDown
        text31_2                matlab.ui.control.Label
        Supportpopup            matlab.ui.control.DropDown
        LoadsTab                matlab.ui.container.Tab
        text35_11               matlab.ui.control.Label
        text35_10               matlab.ui.control.Label
        LoadApplypopup          matlab.ui.control.DropDown
        text24_2                matlab.ui.control.Label
        cGL_M                   matlab.ui.control.NumericEditField
        text40_5                matlab.ui.control.Label
        cGL_W                   matlab.ui.control.NumericEditField
        GLroof                  matlab.ui.control.NumericEditField
        GLtyp                   matlab.ui.control.NumericEditField
        text36_2                matlab.ui.control.Label
        CB1                     matlab.ui.control.CheckBox
        cCL_M                   matlab.ui.control.NumericEditField
        cLL_M                   matlab.ui.control.NumericEditField
        cDL_M                   matlab.ui.control.NumericEditField
        text35_3                matlab.ui.control.Label
        text40_4                matlab.ui.control.Label
        text40_3                matlab.ui.control.Label
        text40_2                matlab.ui.control.Label
        CL                      matlab.ui.control.NumericEditField
        LLroof                  matlab.ui.control.NumericEditField
        LLtyp                   matlab.ui.control.NumericEditField
        DLroof                  matlab.ui.control.NumericEditField
        DLtyp                   matlab.ui.control.NumericEditField
        cCL_W                   matlab.ui.control.NumericEditField
        cLL_W                   matlab.ui.control.NumericEditField
        cDL_W                   matlab.ui.control.NumericEditField
        text35_2                matlab.ui.control.Label
        text44                  matlab.ui.control.Label
        text24                  matlab.ui.control.Label
        text36                  matlab.ui.control.Label
        text37                  matlab.ui.control.Label
        text35                  matlab.ui.control.Label
        text41                  matlab.ui.control.Label
        text40                  matlab.ui.control.Label
        MemberModelingTab       matlab.ui.container.Tab
        text35_15               matlab.ui.control.Label
        text35_14               matlab.ui.control.Label
        Kshearstatus            matlab.ui.control.CheckBox
        text351                 matlab.ui.control.Label
        BeamElementpopup        matlab.ui.control.DropDown
        bond_slip               matlab.ui.control.CheckBox
        text333                 matlab.ui.control.Label
        coeff_cracked           matlab.ui.control.NumericEditField
        text73_3                matlab.ui.control.Label
        ColMppopup              matlab.ui.control.DropDown
        text35_6                matlab.ui.control.Label
        text444                 matlab.ui.control.Label
        edit68                  matlab.ui.control.NumericEditField
        textP2                  matlab.ui.control.Label
        textP1                  matlab.ui.control.Label
        edit67                  matlab.ui.control.NumericEditField
        edit66                  matlab.ui.control.NumericEditField
        edit65                  matlab.ui.control.NumericEditField
        text75_2                matlab.ui.control.Label
        text74_2                matlab.ui.control.Label
        text73_2                matlab.ui.control.Label
        text35_4                matlab.ui.control.Label
        ColElementpopup         matlab.ui.control.DropDown
        MaterialsTab            matlab.ui.container.Tab
        text52_2                matlab.ui.control.Label
        text53_7                matlab.ui.control.Label
        fub                     matlab.ui.control.NumericEditField
        text35_17               matlab.ui.control.Label
        fyb                     matlab.ui.control.NumericEditField
        fu                      matlab.ui.control.NumericEditField
        fuP                     matlab.ui.control.NumericEditField
        text35_16               matlab.ui.control.Label
        fyP                     matlab.ui.control.NumericEditField
        text53_6                matlab.ui.control.Label
        text35_13               matlab.ui.control.Label
        text35_12               matlab.ui.control.Label
        fyGP                    matlab.ui.control.NumericEditField
        fyBrace                 matlab.ui.control.NumericEditField
        muC                     matlab.ui.control.NumericEditField
        Ec                      matlab.ui.control.NumericEditField
        fc                      matlab.ui.control.NumericEditField
        text53_5                matlab.ui.control.Label
        muR                     matlab.ui.control.NumericEditField
        Er                      matlab.ui.control.NumericEditField
        fyR                     matlab.ui.control.NumericEditField
        Materialpopup           matlab.ui.control.DropDown
        text35_9                matlab.ui.control.Label
        text35_8                matlab.ui.control.Label
        text35_7                matlab.ui.control.Label
        mu0                     matlab.ui.control.NumericEditField
        text53_2                matlab.ui.control.Label
        fy                      matlab.ui.control.NumericEditField
        E                       matlab.ui.control.NumericEditField
        text43                  matlab.ui.control.Label
        text53                  matlab.ui.control.Label
        text52                  matlab.ui.control.Label
        SubmitButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        TipWindow;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Build_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';  

            global  ProjectName ProjectPath Units resource_root FrameType
            load(strcat(ProjectPath,ProjectName))

            clc;

            app.Image.ImageSource = strjoin(resource_root+"RBS.jpg");
            app.showMRFButton.Icon = strjoin(resource_root+"scope.png");
            
            if Units==1
                app.text25.Text='Building Geometry [unit: mm]';
                app.text24.Text='Loads [kN/m2]';
                app.text43.Text='Material [kN/mm2]';
                app.text74.Text='kN';
                app.text75.Text='kN/mm';
                convUnit=1000*1000;
            else
                app.text25.Text='Building Geometry [unit: inches]';
                app.text24.Text='Loads [psf]';
                app.text43.Text='Material [ksi]';
                app.text74.Text='kip';
                app.text75.Text='kip/in';
                convUnit=1000*12*12;
            end

            if FrameType~=1
                app.LoadApplypopup.Value = num2str(1);
                app.LoadApplypopup.Enable="off";

                % app.showMRFButton.Enable="off";

                app.NBay.Enable=1;
                app.NBay.Enable="off";

                app.text67.Visible="on";
                app.text30.Visible="on";
                app.text301.Visible="on";
                app.text302.Visible="on";
                app.text303.Visible="on";
                app.BraceLayoutpopup.Visible="on";
                app.MidSpanpopup.Visible="on";
                app.ConnectionEvenpopup.Visible="on";
                app.ConnectionOddpopup.Visible="on";
                app.TipIcon6.Visible="on";

                app.text67.Enable="on";
                app.text30.Enable="on";
                app.text301.Enable="on";
                app.text302.Enable="on";
                app.text303.Enable="on";
                app.BraceLayoutpopup.Enable="on";
                app.MidSpanpopup.Enable="on";
                app.ConnectionEvenpopup.Enable="on";
                app.ConnectionOddpopup.Enable="on";
                app.TipIcon6.Enable="on";
                app.fyBrace.Enable='on';
                app.fyGP.Enable='on';

                app.Connectionpopup.Enable="off";
                app.a.Enable="off";
                app.b.Enable="off";
                app.c.Enable="off";
                app.text304.Enable="off";

            end


            if Definestatus~=1
                
                app.Connectionpopup.Value= '1';
                app.Supportpopup.Value   = '1';
                app.Splicepopup.Value    = '2';
                app.LoadApplypopup.Value = '1';

                if Units==1
                    app.E.Value         = 200;
                    app.fy.Value        = 0.35;
                    app.fu.Value        = 0.47;
                    app.fyP.Value       = 0.35;
                    app.fuP.Value       = 0.47;
                    app.fyb.Value       = 0.9;
                    app.fub.Value       = 1.0;
                    app.fyBrace.Value   = 0.35;
                    app.fyGP.Value      = 0.35;

                    app.Er.Value        = 200;
                    app.fyR.Value       = 0.35;

                    app.Ec.Value        = 0.05;
                    app.fc.Value        = 0.035;
                else
                    app.E.Value         = 29000;
                    app.fy.Value        = 55;
                    app.fu.Value        = 65;
                    app.fyP.Value       = 55;
                    app.fuP.Value       = 65;
                    app.fyb.Value       = 130;
                    app.fub.Value       = 145;
                    app.fyBrace.Value   = 55;
                    app.fyGP.Value      = 55;

                    app.Er.Value        = 29000;
                    app.fyR.Value       = 55;

                    app.Ec.Value        = 2600;
                    app.fc.Value        = 5;          
                end

            else
            
                if FrameType~=1
                    app.BraceLayoutpopup.Value=num2str(BraceLayout);
                    app.MidSpanpopup.Value=num2str(MidSpanConstraint);
                    app.ConnectionEvenpopup.Value=num2str(MFconnectionEven);
                    app.ConnectionOddpopup.Value=num2str(MFconnectionOdd);           
                    app.fyBrace.Value=fyBrace;
                    app.fyGP.Value=fyGP;
                end

                app.DIM1.Value=DIM1;
                app.DIM2.Value=DIM2;
                app.nMRF.Value=nMF;
            
                app.NStory.Value=NStory;
                app.NBay.Value=NBay;
                app.nGC.Value=nGC;
                app.nGB.Value=nGB;
            
                app.TAex1.Value=TAex1;
                app.TAex2.Value=TAex2;
                app.TAin1.Value=TAin1;
                app.TAin2.Value=TAin2;
            
                app.DLtyp.Value=TypicalDL *convUnit;
                app.DLroof.Value=RoofDL    *convUnit;
                app.LLtyp.Value=TypicalLL *convUnit;
                app.LLroof.Value=RoofLL    *convUnit;
                app.CL.Value=Cladding  *convUnit;
                app.GLtyp.Value=TypicalGL*convUnit;
                app.GLroof.Value=RoofGL   *convUnit;
                
                app.CB1.Value=cLoad_status;
                app.cDL_W.Value=cDL_W;
                app.cLL_W.Value=cLL_W;
                app.cCL_W.Value=cCL_W;
                app.cGL_W.Value=cGL_W;
                app.cDL_M.Value=cDL_M;
                app.cLL_M.Value=cLL_M;
                app.cCL_M.Value=cCL_M;
                app.cGL_M.Value=cGL_M;
                
                app.Materialpopup.Value=num2str(SteelMatID);
                app.E.Value   = E;
                app.fy.Value  = fy;
                app.fu.Value  = fu;
                app.fyP.Value = fyP;
                app.fuP.Value = fuP;
                app.fyb.Value = fyb;
                app.fub.Value = fub;
                app.mu0.Value = mu0;
                if FrameType==4
                    app.Er.Value  = Er;
                    app.Ec.Value  = Ec;
                    app.fc.Value  = fc;
                    app.fyR.Value = fyR;
                    app.muR.Value = muR;
                    app.muC.Value = muC;
                    app.BeamElementpopup.Value  = num2str(BeamElementOption);
                    app.coeff_cracked.Value     = coeff_cracked;
                    app.bond_slip.Value         = bond_slip;
                end
                app.Supportpopup.Value       = num2str(Support);
                app.Connectionpopup.Value    = num2str(MFconnection+1);
                app.LoadApplypopup.Value     = num2str(LoadApplyOption);
                app.Orientationpopup.Value   = num2str(Orientation);
                app.EGFConnectionpopup.Value = num2str(EGFconnection);
                app.SupportGFSpopup.Value    = num2str(SupportGFS);

                app.a.Value=a;
                app.b.Value=b;
                app.c.Value=c;

                app.k_beambracing.Value=k_beambracing;
            
                app.Linkpopup.Value=num2str(FloorLink);
                app.Fs.Value=Fs;
                app.Ks.Value=Ks;
                
                app.editExcel.FontColor = [0.0 1.0 0.0];
                app.editExcel.Value =[ExcelFilePath,ExcelFileName];
                
                app.ColElementpopup.Value = num2str(ColElementOption);
                app.edit65.Enable=nSegments;
                app.edit66.Enable=nIntegration;
                app.edit67.Enable=initialGI;
                app.Splicepopup.Value = num2str(SpliceConnection);
                app.Kshearstatus.Value = Kshearstatus;

                app.ColMppopup.Value = num2str(PM_Option);
                app.edit68.Enable=Pscale;
                app.showMRFButton.Visible='on';

            end
            
            if GFX == 1
                app.nGC.Enable='on';
                app.nGB.Enable='on';

                app.text32.Enable='on';
                app.text32_2.Enable='on';
                app.text33.Enable='on';
                
                app.Orientationpopup.Enable  ='on';
                app.EGFConnectionpopup.Enable='on';
                app.SupportGFSpopup.Enable='on';
                app.text311.Enable='on';
                app.text312.Enable='on';
                app.text313.Enable='on';
                app.text314.Enable='on';

                if app.EGFConnectionpopup.Value == 1
                    app.fu.Enable       = 'off';
                    app.fyP.Enable      = 'off';
                    app.fuP.Enable      = 'off';
                    app.fyb.Enable      = 'off';
                    app.fub.Enable      = 'off';
                end
                
            else
                app.nGC.Enable='off';
                app.nGB.Enable='off';

                app.text32.Enable='off';
                app.text32_2.Enable='off';
                app.text33.Enable='off';

                app.Orientationpopup.Enable  ='off';
                app.EGFConnectionpopup.Enable='off';
                app.SupportGFSpopup.Enable='off';
                app.SupportGFSpopup.Value = '2';

                app.text311.Enable='off';
                app.text312.Enable='off';
                app.text313.Enable='off';
                app.text314.Enable='off';
            end


            if FrameType ~= 4
                app.text351.Enable='off';
                app.BeamElementpopup.Enable='off';
                
                app.Er.Enable='off';
                app.Ec.Enable='off';
                app.fc.Enable='off';
                app.fyR.Enable='off';
                app.muR.Enable='off';
                app.muC.Enable='off';

                app.text73_3.Visible='off';
                app.text333.Visible='off';
                app.coeff_cracked.Visible='off';
                app.bond_slip.Visible='off';
            else
                
                app.Splicepopup.Enable='off';
                app.Connectionpopup.Enable='off';
                app.Image.Enable='off';
                app.a.Enable='off';
                app.b.Enable='off';
                app.c.Enable='off';

                app.text351.Enable='on';
                app.BeamElementpopup.Enable='on';
                
                app.Er.Enable='on';
                app.Ec.Enable='on';
                app.fc.Enable='on';
                app.fyR.Enable='on';
                app.muR.Enable='on';
                app.muC.Enable='on';

                app.text73_3.Visible='on';
                app.text333.Visible='on';
                app.coeff_cracked.Visible='on';
                app.bond_slip.Visible='on';
            end
                        
            Connectionpopup_Callback(app);
            CB1ValueChanged(app);
            LinkpopupValueChanged(app);
            ColElementpopupValueChanged(app);
            ColMppopupValueChanged(app);

        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory FrameType ProjectPath ProjectName

            load(strcat(ProjectPath,ProjectName),'GFX');

            NStory    = app.NStory.Value;
            NBay      = app.NBay.Value;
            FloorLink = str2double(app.Linkpopup.Value);
          
            [ExcelFileName,ExcelFilePath] = uigetfile('*.xlsx','Select the .xlsx file containg building geometry and member sizes');
            
            if ExcelFileName==0; return; end

            try
                app.editExcel.FontColor = [1.0 1.0 0.0];
                app.editExcel.Value     = ('Please wait ...'); drawnow;

                % Read Frame Cross-Section Data From Excel
                if FrameType~=4
                    Import_ExcelData(FrameType, ExcelFilePath,ExcelFileName,NStory,NBay,FloorLink, GFX);
                else
                    Import_ExcelData_RCMRF(ExcelFilePath,ExcelFileName,NStory,NBay,FloorLink);                
                end
                
                app.editExcel.FontColor = [0.0 1.0 0.0];
                app.editExcel.Value     = 'Import successful!'; drawnow;

                app.showMRFButton.Visible='on';
            catch ME
                cd (MainDirectory)
                write_Error(ME);
                app.editExcel.FontColor = [1 0. 0.];
                app.editExcel.Value     = 'Error importing file. See Error file!'; drawnow;
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath FrameType resource_root

            load(strcat(ProjectPath,ProjectName));
            
            if resource_root==''; load(strjoin(resource_root+"Material_Database.mat")); else; load resources\database\Material_Database.mat; end

            if exist ('ExcelFileName')==0
                errordlg('Please import first the Excel file containing MRF sections!');
                return;
            end

            if Units==1; convUnit=1000*1000; else; convUnit=1000*12*12; end
            
            try
                
            % Geometry
            DIM1    = app.DIM1.Value;
            DIM2    = app.DIM2.Value;
            nMF     = app.nMRF.Value;
            NStory  = app.NStory.Value;
            NBay    = app.NBay.Value;
            nGC     = app.nGC.Value;
            nGB     = app.nGB.Value;
            TAex1  = app.TAex1.Value;
            TAex2  = app.TAex2.Value;
            TAin1  = app.TAin1.Value;
            TAin2  = app.TAin2.Value;

            if FrameType~=1
                BraceLayout       = str2double(app.BraceLayoutpopup.Value);
                MFconnectionEven  = str2double(app.ConnectionEvenpopup.Value);
                MFconnectionOdd   = str2double(app.ConnectionOddpopup.Value);
                MidSpanConstraint = str2double(app.MidSpanpopup.Value);
                fyBrace = app.fyBrace.Value;
                fyGP    = app.fyGP.Value;
            end

            % Check dimensions
            if DIM1 < 2*TAex1+(NBay+1-2)*TAin1
                errordlg('The dimension of the building in Dir #1 is less than the sum of those of the columns! Please check.');
                return;
            end
            
            if nMF==1
                errordlg('The number of main frames cannot be less than 2 per building in a given orthogonal direction!');
                return;
            end

            if FrameType==1
                  [Column_Index, Beam_Index, Brace_Index, XSecID] = Get_Section_Database_Index(FrameType, NStory, NBay, MF_COLUMNS, MF_BEAMS, BRACES, Units);
            end
            [GridX, GridY, AXIS, Elevation, HalfElevation, XTickLabel, YTickLabel] = Get_Grid (NStory, NBay,HStory, WBay);

            FootPrintArea = DIM1 * DIM2;
            Perimeter     = 2 * DIM1 + 2 * DIM2;
            
            if nMF==2
                TA_MF         = DIM1 * DIM2 / nMF;            
                TD_MF         = DIM2 / nMF;            
            else
                TA_MF         = DIM1 * DIM2 / (nMF-1);            
                TD_MF         = DIM2 / (nMF-1);                 
            end
            TribAreaEx    = TAex1 * TAex2;
            TribAreaIn    = TAin1 * TAin2;

            LAYOUT = struct('DIM1',DIM1,'DIM2',DIM2,'nMF',nMF,'NStory',NStory,'NBay',NBay,'HStory',HStory,'WBay',WBay,'nGC',nGC,'nGB',nGB,'TAex1',TAex1,'TAex2',TAex2,'TAin1',TAin1,'TAin2',TAin2,'FootPrintArea',FootPrintArea,'Perimeter',Perimeter,'TA_MF',TA_MF,'TD_MF',TD_MF,'TribAreaEx',TribAreaEx,'TribAreaIn',TribAreaIn,'GridX',GridX,'GridY',GridX,'AXIS',AXIS,'Elevation',Elevation);

            % Loads
            TypicalDL   = app.DLtyp.Value /convUnit;
            RoofDL      = app.DLroof.Value /convUnit;
            TypicalLL   = app.LLtyp.Value /convUnit;
            RoofLL      = app.LLroof.Value /convUnit;
            Cladding    = app.CL.Value /convUnit;
            TypicalGL   = app.GLtyp.Value/convUnit;
            RoofGL      = app.GLroof.Value/convUnit;
                
            if Units==2 && TypicalDL*convUnit<1
                warndlg('The entered loads do not seem to be in the correct units (psf)! Please check.', 'Load Alert');
            end

            % Load Combination Coefficeints
            cLoad_status=app.CB1.Value;
            cDL_W  = app.cDL_W.Value;
            cLL_W  = app.cLL_W.Value;
            cCL_W  = app.cCL_W.Value;
            cGL_W  = app.cGL_W.Value;
            cDL_M  = app.cDL_M.Value;
            cLL_M  = app.cLL_M.Value;
            cCL_M  = app.cCL_M.Value;
            cGL_M  = app.cGL_M.Value;
            
            LoadApplyOption = str2double(app.LoadApplypopup.Value);

            LOADS = struct('TypicalDL',TypicalDL,'RoofDL',RoofDL,'TypicalLL',TypicalLL,'RoofLL',RoofLL,'Cladding',Cladding,'TypicalGL',TypicalGL,'RoofGL',RoofGL,'cLoad_status',cLoad_status,'cDL_W',cDL_W,'cLL_W',cLL_W,'cCL_W',cCL_W,'cGL_W',cGL_W,'cDL_M',cDL_M,'cLL_M',cLL_M,'cCL_M',cCL_M,'cGL_M',cGL_M,'LoadApplyOption',LoadApplyOption);

            % Materials
            if resource_root==''; load(strjoin(resource_root+"Material_Database.mat")); else; load resources\database\Material_Database.mat; end

            SteelMatID   = str2double(app.Materialpopup.Value);
            SteelMatType = app.Materialpopup.Items{SteelMatID};
            E   = app.E.Value;
            fy  = app.fy.Value;
            fu  = app.fu.Value;
            mu0 = app.mu0.Value;

            fyP    = app.fyP.Value;
            fuP    = app.fuP.Value;
            fyb    = app.fyb.Value;
            fub    = app.fub.Value;

            Ec  = app.Ec.Value;
            fc  = app.fc.Value;
            muC = app.muC.Value;
            Er  = app.Er.Value;
            fyR = app.fyR.Value;
            muR = app.muR.Value;
            
            MATERIALS = struct('SteelMatID',SteelMatID,'SteelMatType',SteelMatType,'E',E,'fy',fy,'fu',fu,'mu0',mu0,'fyP',fyP,'fuP',fuP,'fyb',fyb,'fub',fub,'Ec',Ec,'fc',fc,'muC',muC,'Er',Er,'fyR',fyR,'muR',muR,'fyBrace',fyBrace,'fyGP',fyGP);

            % Supports & Connections
            Support           = str2double(app.Supportpopup.Value);
            MFconnection      = str2double(app.Connectionpopup.Value)-1;
            SpliceConnection  = str2double(app.Splicepopup.Value);
            Orientation       = str2double(app.Orientationpopup.Value);
            EGFconnection     = str2double(app.EGFConnectionpopup.Value);
            SupportGFS        = str2double(app.SupportGFSpopup.Value);
            
            a = app.a.Value;
            b = app.b.Value;
            c = app.c.Value;

            k_beambracing = app.k_beambracing.Value;

            if GFX ==1 && EGFconnection >1 && exist('CONNECTIONS')==0
                errordlg('You must re-import the EXCEL file and make sure the GFS endplate connection are defined in it.');
                return;
            end
            
            if app.Linkpopup.Value=='1'
                FloorLink = 1;
                Fs        = 1000000.;
                Ks        = 0;
            else
                FloorLink = 2;
                Fs        = app.Fs.Value;
                Ks        = app.Ks.Value;
            end
            
            ColElementOption  = str2double(app.ColElementpopup.Value);
            BeamElementOption  = str2double(app.BeamElementpopup.Value);
            nSegments    = app.edit65.Value;
            nIntegration = app.edit66.Value;
            initialGI    = app.edit67.Value;
            Kshearstatus = app.Kshearstatus.Value;

            if app.ColMppopup.Value=='1'
                PM_Option=1;
                Pscale=1.0;
            else
                PM_Option=2;
                Pscale=app.edit68.Value;
            end
            
            coeff_cracked = app.coeff_cracked.Value;
            bond_slip     = app.bond_slip.Value;
                
            DampModeJ=min(DampModeJ,NStory);
            
            Definestatus = 1;
            
            % Save Data to .mat File
            save(strcat(ProjectPath,ProjectName), '-regexp', '^(?!(event|app)$).')

            app.MainApp.status1.BackgroundColor  = 'g';
            app.MainApp.status1.FontColor='k';
            if     FrameType==1
                app.MainApp.status1.Text=[num2str(NStory),'-story MRF']; 
            elseif FrameType == 2
                if BraceLayout==1; app.MainApp.status1.Text=[num2str(NStory),'-story CBF - X-Brace']; end
                if BraceLayout==2; app.MainApp.status1.Text=[num2str(NStory),'-story CBF - Chevron']; end
            elseif FrameType == 3
                if BraceLayout==1; app.MainApp.status1.Text=[num2str(NStory),'-story EBF - Double']; end
                if BraceLayout==2; app.MainApp.status1.Text=[num2str(NStory),'-story EBF - Single']; end
            elseif FrameType==4;   app.MainApp.status1.Text=[num2str(NStory),'-story RC MRF']; end
            app.MainApp.status1b.BackgroundColor = 'g';
            
            app.MainApp.AnalysisButton.Enable       = 'on';
            app.MainApp.GetperiodButton.Enable      = 'on';
            app.MainApp.BuildtclfileButton.Enable   = 'on';
            
            app.MainApp.ProgressText.FontColor='g';
            app.MainApp.ProgressText.Text=('Building data defined'); drawnow;

            fclose all;
            
            figure1CloseRequest(app)
            
            catch ME
                errordlg('An error has occured. Please see Error file!');
                cd(MainDirectory)
                write_Error(ME);
            end
        end

        % Value changed function: Connectionpopup
        function Connectionpopup_Callback(app, event)
            global FrameType
            if app.Connectionpopup.Value == '1' && FrameType ~= 4
                app.a.Enable='on';
                app.b.Enable='on';
                app.c.Enable='on';
                
                app.a.Value=0.625;
                app.b.Value=0.75;
                app.c.Value=0.25;
                
                app.Image.Visible='on';
            else
                app.a.Enable='off';
                app.b.Enable='off';
                app.c.Enable='off';
                
                app.a.Value=0.1;
                app.b.Value=0.0;
                app.c.Value=0.0;
                
                app.Image.Visible='off';
            end
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value == 1
                app.cDL_W.Enable='off';
                app.cLL_W.Enable='off'; 
                app.cCL_W.Enable='off'; 
                app.cDL_M.Enable='off'; 
                app.cLL_M.Enable='off'; 
                app.cCL_M.Enable='off'; 
                app.cGL_W.Enable='off'; 
                app.cGL_M.Enable='off'; 
                
                app.cDL_W.Value=1.05;
                app.cLL_W.Value=0.25; 
                app.cCL_W.Value=1.05; 
                app.cDL_M.Value=1.0; 
                app.cLL_M.Value=0.0; 
                app.cCL_M.Value=1.0; 
                app.cGL_W.Value=1.0; 
                app.cGL_M.Value=1.0; 
            else
                app.cDL_W.Enable='on';
                app.cLL_W.Enable='on'; 
                app.cCL_W.Enable='on'; 
                app.cDL_M.Enable='on'; 
                app.cLL_M.Enable='on'; 
                app.cCL_M.Enable='on';    
                app.cGL_W.Enable='on';                 
                app.cGL_M.Enable='on'; 
            end
            
        end

        % Value changed function: Linkpopup
        function LinkpopupValueChanged(app, event)
            if app.Linkpopup.Value == '1'
                app.Fs.Enable='off';
                app.Ks.Enable='off';
                app.FuseF_ProfileCB.Enable='off';

                app.Fs.Value = 10000;
                app.Ks.Value = 0.0;
            else
                app.Fs.Enable='on';
                app.Ks.Enable='on';
                app.FuseF_ProfileCB.Enable='on';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible            = 'off';
            app.MainApp.ProgressText.Text=('Building data defined'); drawnow;
            delete(app)
        end

        % Value changed function: ColElementpopup
        function ColElementpopupValueChanged(app, event)
            if app.ColElementpopup.Value == '1'
                app.edit65.Enable='off';
                app.edit66.Enable='off';
                app.edit67.Enable='off';

                app.ColMppopup.Enable = 'on';
                app.ColMppopup.Value = '2';
                app.edit68.Enable = 'on';
                app.edit68.Value = 1.0;
                app.textP1.Enable='on';
                app.textP2.Enable='on';
            else
                app.edit65.Enable='on';
                app.edit66.Enable='on';
                app.edit67.Enable='on';

                app.ColMppopup.Enable = 'off';
                app.ColMppopup.Value = '2';
                app.edit68.Enable = 'off';
                app.edit68.Value = 1.0;
                app.textP1.Enable='off';
                app.textP2.Enable='off';
            end

            % if app.ColElementpopup.Value == '4'
            %     warndlg('Option not available yet!');
            %     app.ColElementpopup.Value = '1';
            % end
        end

        % Value changed function: ColMppopup
        function ColMppopupValueChanged(app, event)
            if app.ColMppopup.Value == '2'
                app.edit68.Enable='on';
            else
                app.edit68.Enable='off';
            end
        end

        % Value changed function: Materialpopup
        function MaterialpopupValueChanged(app, event)
            global MainDirectory Units resource_root
            cd(MainDirectory)

            if resource_root==''; load(strjoin(resource_root+"Material_Database.mat")); else; load resources\database\Material_Database.mat; end
            if Units==1; transUnit=6.89476/1000; else; transUnit=1; end
            x=str2double(app.Materialpopup.Value);
            app.E.Value=Material.E(x+1,1)*transUnit;
            app.fy.Value=Material.fy(x+1,1)*transUnit;
            app.fu.Value=Material.fu(x+1,1)*transUnit;
        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            global resource_root

            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread(strjoin(resource_root+"FLoor Link.jpg"));
            imshow(data); 
        end

        % Button pushed function: showMRFButton
        function showMRFButtonPushed(app, event)
            global ProjectPath ProjectName
            NStory  = app.NStory.Value;
            NBay    = app.NBay.Value;
            Support = str2double(app.Supportpopup.Value);

            save(strcat(ProjectPath,ProjectName), 'NStory','NBay', 'Support', '-append')

            plot_MRF_with_labels(1);
        end

        % Button pushed function: TipIcon6
        function TipIcon6ButtonPushed(app, event)
            global resource_root
            
            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread(strjoin(resource_root+"MidPointConstraint.jpg"));
            imshow(data); 
        end

        % Value changed function: EGFConnectionpopup
        function EGFConnectionpopupValueChanged(app, event)
            if app.EGFConnectionpopup.Value ~='1'
                warndlg('Option not available yet!');
                app.EGFConnectionpopup.Value = '1';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Building Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'BuildButton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.BuildingGeometryTab = uitab(app.TabGroup);
            app.BuildingGeometryTab.Title = 'Building Geometry';
            app.text31 = uilabel(app.BuildingGeometryTab);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Building Plan Dimensions';
            app.text32 = uilabel(app.BuildingGeometryTab);
            app.text32.Tag = 'text32';
            app.text32.Text = 'GFS Configuration';
            app.text33 = uilabel(app.BuildingGeometryTab);
            app.text33.Tag = 'text33';
            app.text33.Tooltip = {'number of GFS beams in the considered orthogonal direction only'};
            app.text33.Text = 'Number of Beams';
            app.text42 = uilabel(app.BuildingGeometryTab);
            app.text42.Tag = 'text42';
            app.text42.Text = 'MF Configuration';
            app.text45 = uilabel(app.BuildingGeometryTab);
            app.text45.Tag = 'text45';
            app.text45.Text = 'Number of Bays';
            app.text46 = uilabel(app.BuildingGeometryTab);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Exterior MF Column Tributary Area';
            app.text47 = uilabel(app.BuildingGeometryTab);
            app.text47.Tag = 'text47';
            app.text47.Text = 'Interior MF Column Tributary Area';
            app.text49 = uilabel(app.BuildingGeometryTab);
            app.text49.Tag = 'text49';
            app.text49.Text = 'x';
            app.text50 = uilabel(app.BuildingGeometryTab);
            app.text50.Tag = 'text50';
            app.text50.Text = 'x';
            app.text51 = uilabel(app.BuildingGeometryTab);
            app.text51.Tag = 'text51';
            app.text51.Text = 'x';
            app.text65 = uilabel(app.BuildingGeometryTab);
            app.text65.Tag = 'text65';
            app.text65.Text = 'No. of MFs in the Considered Direction';
            app.text42_2 = uilabel(app.BuildingGeometryTab);
            app.text42_2.Tag = 'text42';
            app.text42_2.Text = 'Number of Stories';
            app.text32_2 = uilabel(app.BuildingGeometryTab);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'Number of Columns';
            app.text25 = uilabel(app.BuildingGeometryTab);
            app.text25.Tag = 'text40';
            app.text25.Text = 'Building Geometry [unit: inches]';
            app.text25_2 = uilabel(app.BuildingGeometryTab);
            app.text25_2.Tag = 'text40';
            app.text25_2.Text = 'Building Members & Detail';
            app.BrowseButton = uibutton(app.BuildingGeometryTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tag = 'BrowseButton';
            app.BrowseButton.Text = 'browse';
            app.text84 = uilabel(app.BuildingGeometryTab);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Import Excel File';
            app.DIM1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.DIM1.Limits = [0.0001 Inf];
            app.DIM1.ValueDisplayFormat = '%.1f';
            app.DIM1.Value = 100;
            app.DIM2 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.DIM2.Limits = [0.0001 Inf];
            app.DIM2.ValueDisplayFormat = '%.1f';
            app.DIM2.Value = 100;
            app.TAex1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.TAex1.Limits = [0.0001 Inf];
            app.TAex1.ValueDisplayFormat = '%.1f';
            app.TAex1.Value = 100;
            app.TAex2 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.TAex2.Limits = [0.0001 Inf];
            app.TAex2.ValueDisplayFormat = '%.1f';
            app.TAex2.Value = 100;
            app.TAin1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.TAin1.Limits = [0.0001 Inf];
            app.TAin1.ValueDisplayFormat = '%.1f';
            app.TAin1.Value = 100;
            app.TAin2 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.TAin2.Limits = [0.0001 Inf];
            app.TAin2.ValueDisplayFormat = '%.1f';
            app.TAin2.Value = 100;
            app.nMRF = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.nMRF.Limits = [2 Inf];
            app.nMRF.ValueDisplayFormat = '%.0f';
            app.nMRF.Value = 2;
            app.NStory = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.NStory.Limits = [1 Inf];
            app.NStory.ValueDisplayFormat = '%.0f';
            app.NStory.Value = 1;
            app.NBay = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.NBay.Limits = [1 Inf];
            app.NBay.ValueDisplayFormat = '%.0f';
            app.NBay.Value = 1;
            app.nGC = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.nGC.Limits = [1 Inf];
            app.nGC.ValueDisplayFormat = '%.0f';
            app.nGC.Value = 10;
            app.nGB = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.nGB.Limits = [1 Inf];
            app.nGB.ValueDisplayFormat = '%.0f';
            app.nGB.Tooltip = {'number of GFS beams in the considered orthogonal direction only'};
            app.nGB.Value = 10;
            app.editExcel = uieditfield(app.BuildingGeometryTab, 'text');
            app.editExcel.Editable = 'off';
            app.text31_5 = uilabel(app.BuildingGeometryTab);
            app.text31_5.Tag = 'text31';
            app.text31_5.Text = 'Dir #1';
            app.text31_6 = uilabel(app.BuildingGeometryTab);
            app.text31_6.Tag = 'text31';
            app.text31_6.Text = 'Dir #2';
            app.showMRFButton = uibutton(app.BuildingGeometryTab, 'push');
            app.showMRFButton.ButtonPushedFcn = createCallbackFcn(app, @showMRFButtonPushed, true);
            app.showMRFButton.Tag = 'BrowseButton';
            app.showMRFButton.Icon = fullfile(pathToMLAPP, 'scope.png');
            app.showMRFButton.Visible = 'off';
            app.showMRFButton.Tooltip = {'Show frame sections'};
            app.showMRFButton.Text = '';
            app.text67 = uilabel(app.BuildingGeometryTab);
            app.text67.Tag = 'text67';
            app.text67.Enable = 'off';
            app.text67.Text = 'Bracing Layout';
            app.BraceLayoutpopup = uidropdown(app.BuildingGeometryTab);
            app.BraceLayoutpopup.Items = {'X-Bracing', 'Chevron'};
            app.BraceLayoutpopup.ItemsData = {'1', '2'};
            app.BraceLayoutpopup.Tag = 'BraceLayoutpopup';
            app.BraceLayoutpopup.Enable = 'off';
            app.BraceLayoutpopup.Value = '1';
            app.SupportsConnectionsTab = uitab(app.TabGroup);
            app.SupportsConnectionsTab.Title = 'Supports & Connections';
            app.Supportpopup = uidropdown(app.SupportsConnectionsTab);
            app.Supportpopup.Items = {'Fixed', 'Pinned'};
            app.Supportpopup.ItemsData = {'1', '2'};
            app.Supportpopup.Tag = 'Supportpopup';
            app.Supportpopup.Value = '1';
            app.text31_2 = uilabel(app.SupportsConnectionsTab);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'MF Column Supports';
            app.Connectionpopup = uidropdown(app.SupportsConnectionsTab);
            app.Connectionpopup.Items = {'Reduced Beam Section', 'Welded/Bolted (Full-Section)'};
            app.Connectionpopup.ItemsData = {'1', '2'};
            app.Connectionpopup.ValueChangedFcn = createCallbackFcn(app, @Connectionpopup_Callback, true);
            app.Connectionpopup.Tag = 'Connectionpopup';
            app.Connectionpopup.Value = '1';
            app.text56 = uilabel(app.SupportsConnectionsTab);
            app.text56.Tag = 'text56';
            app.text56.Text = 'a =';
            app.text57 = uilabel(app.SupportsConnectionsTab);
            app.text57.Tag = 'text57';
            app.text57.Text = 'b =';
            app.text58 = uilabel(app.SupportsConnectionsTab);
            app.text58.Tag = 'text58';
            app.text58.Text = 'c =';
            app.text69 = uilabel(app.SupportsConnectionsTab);
            app.text69.Tag = 'text69';
            app.text69.Text = 'bf';
            app.text70 = uilabel(app.SupportsConnectionsTab);
            app.text70.Tag = 'text70';
            app.text70.Text = 'd';
            app.text71 = uilabel(app.SupportsConnectionsTab);
            app.text71.Tag = 'text71';
            app.text71.Text = 'bf';
            app.text304 = uilabel(app.SupportsConnectionsTab);
            app.text304.Tag = 'text31';
            app.text304.Text = 'MF Connection';
            app.Linkpopup = uidropdown(app.SupportsConnectionsTab);
            app.Linkpopup.Items = {'Rigid', 'Flexible'};
            app.Linkpopup.ItemsData = {'1', '2'};
            app.Linkpopup.ValueChangedFcn = createCallbackFcn(app, @LinkpopupValueChanged, true);
            app.Linkpopup.Tag = 'Linkpopup';
            app.Linkpopup.Value = '1';
            app.text72 = uilabel(app.SupportsConnectionsTab);
            app.text72.Tag = 'text72';
            app.text72.Text = 'Fs=';
            app.text73 = uilabel(app.SupportsConnectionsTab);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Ks=';
            app.text74 = uilabel(app.SupportsConnectionsTab);
            app.text74.Tag = 'text74';
            app.text74.Text = 'kip';
            app.text75 = uilabel(app.SupportsConnectionsTab);
            app.text75.Tag = 'text75';
            app.text75.Text = 'kip/in';
            app.text31_4 = uilabel(app.SupportsConnectionsTab);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'MF to EGF Link Condition';
            app.Image = uiimage(app.SupportsConnectionsTab);
            app.Image.ImageSource = 'RBS.jpg';
            app.a = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.a.Limits = [0 Inf];
            app.a.ValueDisplayFormat = '%.3f';
            app.a.Value = 0.625;
            app.b = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.b.Limits = [0 Inf];
            app.b.ValueDisplayFormat = '%.3f';
            app.b.Value = 0.75;
            app.c = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.c.Limits = [0 Inf];
            app.c.ValueDisplayFormat = '%.3f';
            app.c.Value = 0.25;
            app.Fs = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.Fs.Limits = [0 Inf];
            app.Fs.Value = 10000;
            app.Ks = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.Ks.Limits = [0 Inf];
            app.FuseF_ProfileCB = uicheckbox(app.SupportsConnectionsTab);
            app.FuseF_ProfileCB.Tag = 'FuseF_ProfileCB';
            app.FuseF_ProfileCB.Text = 'Non-uniform Fs Profile';
            app.Splicepopup = uidropdown(app.SupportsConnectionsTab);
            app.Splicepopup.Items = {'Pinned', 'Fixed'};
            app.Splicepopup.ItemsData = {'1', '2'};
            app.Splicepopup.Tag = 'Supportpopup';
            app.Splicepopup.Value = '1';
            app.text31_7 = uilabel(app.SupportsConnectionsTab);
            app.text31_7.Tag = 'text31';
            app.text31_7.Text = 'MF Column Splice Condition';
            app.TipIcon5 = uibutton(app.SupportsConnectionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.text302 = uilabel(app.SupportsConnectionsTab);
            app.text302.Tag = 'text78';
            app.text302.Enable = 'off';
            app.text302.Visible = 'off';
            app.text302.Text = 'Even floors';
            app.ConnectionEvenpopup = uidropdown(app.SupportsConnectionsTab);
            app.ConnectionEvenpopup.Items = {'Pinned', 'Shear', 'Moment'};
            app.ConnectionEvenpopup.ItemsData = {'1', '2', '3'};
            app.ConnectionEvenpopup.Tag = 'ConnectionEvenpopup';
            app.ConnectionEvenpopup.Enable = 'off';
            app.ConnectionEvenpopup.Visible = 'off';
            app.ConnectionEvenpopup.Value = '1';
            app.text30 = uilabel(app.SupportsConnectionsTab);
            app.text30.Tag = 'text31';
            app.text30.Enable = 'off';
            app.text30.Visible = 'off';
            app.text30.Text = 'BF Beam Mid-Span Condition';
            app.MidSpanpopup = uidropdown(app.SupportsConnectionsTab);
            app.MidSpanpopup.Items = {'Free to Sag', 'Floor-Constrained'};
            app.MidSpanpopup.ItemsData = {'1', '2'};
            app.MidSpanpopup.Tag = 'MidSpanpopup';
            app.MidSpanpopup.Enable = 'off';
            app.MidSpanpopup.Visible = 'off';
            app.MidSpanpopup.Value = '1';
            app.text303 = uilabel(app.SupportsConnectionsTab);
            app.text303.Tag = 'text79';
            app.text303.Enable = 'off';
            app.text303.Visible = 'off';
            app.text303.Text = 'Odd floors';
            app.text301 = uilabel(app.SupportsConnectionsTab);
            app.text301.Tag = 'text31';
            app.text301.Enable = 'off';
            app.text301.Visible = 'off';
            app.text301.Text = 'BF Connection';
            app.ConnectionOddpopup = uidropdown(app.SupportsConnectionsTab);
            app.ConnectionOddpopup.Items = {'Pinned', 'Shear', 'Moment'};
            app.ConnectionOddpopup.ItemsData = {'1', '2', '3'};
            app.ConnectionOddpopup.Tag = 'ConnectionOddpopup';
            app.ConnectionOddpopup.Enable = 'off';
            app.ConnectionOddpopup.Visible = 'off';
            app.ConnectionOddpopup.Value = '1';
            app.TipIcon6 = uibutton(app.SupportsConnectionsTab, 'push');
            app.TipIcon6.ButtonPushedFcn = createCallbackFcn(app, @TipIcon6ButtonPushed, true);
            app.TipIcon6.Enable = 'off';
            app.TipIcon6.Visible = 'off';
            app.TipIcon6.Text = '?';
            app.text71_2 = uilabel(app.SupportsConnectionsTab);
            app.text71_2.Tag = 'text71';
            app.text71_2.Text = 'L';
            app.text58_2 = uilabel(app.SupportsConnectionsTab);
            app.text58_2.Tag = 'text58';
            app.text58_2.Text = 'MF Beam is laterally braced at          segments';
            app.k_beambracing = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.k_beambracing.Limits = [0.2 1];
            app.k_beambracing.ValueDisplayFormat = '%.3f';
            app.k_beambracing.Tooltip = {'The laterally braced points along the beam length'};
            app.k_beambracing.Value = 0.5;
            app.EGFConnectionpopup = uidropdown(app.SupportsConnectionsTab);
            app.EGFConnectionpopup.Items = {'Shear-tab', 'Header-plate', 'Flush endplate', 'Extended endplate'};
            app.EGFConnectionpopup.ItemsData = {'1', '2', '3', '4'};
            app.EGFConnectionpopup.ValueChangedFcn = createCallbackFcn(app, @EGFConnectionpopupValueChanged, true);
            app.EGFConnectionpopup.Tag = 'Connectionpopup';
            app.EGFConnectionpopup.Value = '1';
            app.text312 = uilabel(app.SupportsConnectionsTab);
            app.text312.Tag = 'text78';
            app.text312.Text = 'Column orientation';
            app.Orientationpopup = uidropdown(app.SupportsConnectionsTab);
            app.Orientationpopup.Items = {'Weak-axis', 'Strong-axis'};
            app.Orientationpopup.ItemsData = {'1', '2'};
            app.Orientationpopup.Tag = 'Orientationpopup';
            app.Orientationpopup.Tooltip = {'Orientation of the gravity column cross-section with respect to the considered building direction'};
            app.Orientationpopup.Value = '1';
            app.text313 = uilabel(app.SupportsConnectionsTab);
            app.text313.Tag = 'text78';
            app.text313.Text = 'Gravity connection type';
            app.text311 = uilabel(app.SupportsConnectionsTab);
            app.text311.Tag = 'text31';
            app.text311.Text = 'GFS Specifics';
            app.text35_18 = uilabel(app.SupportsConnectionsTab);
            app.text35_18.Tag = 'text35';
            app.text35_18.Text = '----------------------------------------------------------------------';
            app.text35_19 = uilabel(app.SupportsConnectionsTab);
            app.text35_19.Tag = 'text35';
            app.text35_19.Text = '----------------------------------------------------------------------';
            app.text35_20 = uilabel(app.SupportsConnectionsTab);
            app.text35_20.Tag = 'text35';
            app.text35_20.Text = '----------------------------------------------------------------------';
            app.SupportGFSpopup = uidropdown(app.SupportsConnectionsTab);
            app.SupportGFSpopup.Items = {'Fixed', 'Pinned'};
            app.SupportGFSpopup.ItemsData = {'1', '2'};
            app.SupportGFSpopup.Tag = 'Supportpopup';
            app.SupportGFSpopup.Value = '2';
            app.text314 = uilabel(app.SupportsConnectionsTab);
            app.text314.Tag = 'text78';
            app.text314.Text = 'Support';
            app.LoadsTab = uitab(app.TabGroup);
            app.LoadsTab.Title = 'Loads';
            app.text40 = uilabel(app.LoadsTab);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Typical';
            app.text41 = uilabel(app.LoadsTab);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Roof';
            app.text35 = uilabel(app.LoadsTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Floor Dead Load';
            app.text37 = uilabel(app.LoadsTab);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Cladding Load';
            app.text36 = uilabel(app.LoadsTab);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Floor Live Load';
            app.text24 = uilabel(app.LoadsTab);
            app.text24.Tag = 'text40';
            app.text24.Text = 'Loads [psf]';
            app.text44 = uilabel(app.LoadsTab);
            app.text44.Tag = 'text40';
            app.text44.Text = 'Load Combination Coefficients';
            app.text35_2 = uilabel(app.LoadsTab);
            app.text35_2.Tag = 'text35';
            app.text35_2.Text = 'Seismic weight';
            app.cDL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cDL_W.Limits = [0 Inf];
            app.cDL_W.ValueDisplayFormat = '%.2f';
            app.cDL_W.Value = 1.05;
            app.cLL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cLL_W.Limits = [0 Inf];
            app.cLL_W.ValueDisplayFormat = '%.2f';
            app.cLL_W.Value = 1.25;
            app.cCL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cCL_W.Limits = [0 Inf];
            app.cCL_W.ValueDisplayFormat = '%.2f';
            app.cCL_W.Value = 1.05;
            app.DLtyp = uieditfield(app.LoadsTab, 'numeric');
            app.DLtyp.Limits = [0 Inf];
            app.DLroof = uieditfield(app.LoadsTab, 'numeric');
            app.DLroof.Limits = [0 Inf];
            app.LLtyp = uieditfield(app.LoadsTab, 'numeric');
            app.LLtyp.Limits = [0 Inf];
            app.LLroof = uieditfield(app.LoadsTab, 'numeric');
            app.LLroof.Limits = [0 Inf];
            app.CL = uieditfield(app.LoadsTab, 'numeric');
            app.CL.Limits = [0 Inf];
            app.text40_2 = uilabel(app.LoadsTab);
            app.text40_2.Tag = 'text40';
            app.text40_2.Text = 'Dead';
            app.text40_3 = uilabel(app.LoadsTab);
            app.text40_3.Tag = 'text40';
            app.text40_3.Text = 'Live';
            app.text40_4 = uilabel(app.LoadsTab);
            app.text40_4.Tag = 'text40';
            app.text40_4.Text = 'Cladding';
            app.text35_3 = uilabel(app.LoadsTab);
            app.text35_3.Tag = 'text35';
            app.text35_3.Text = 'Mass';
            app.cDL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cDL_M.Limits = [0 Inf];
            app.cDL_M.ValueDisplayFormat = '%.2f';
            app.cDL_M.Value = 1;
            app.cLL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cLL_M.Limits = [0 Inf];
            app.cLL_M.ValueDisplayFormat = '%.2f';
            app.cCL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cCL_M.Limits = [0 Inf];
            app.cCL_M.ValueDisplayFormat = '%.2f';
            app.cCL_M.Value = 1;
            app.CB1 = uicheckbox(app.LoadsTab);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'default values';
            app.CB1.Value = true;
            app.text36_2 = uilabel(app.LoadsTab);
            app.text36_2.Tag = 'text36';
            app.text36_2.Text = 'Floor Generic Load';
            app.GLtyp = uieditfield(app.LoadsTab, 'numeric');
            app.GLtyp.Limits = [0 Inf];
            app.GLroof = uieditfield(app.LoadsTab, 'numeric');
            app.GLroof.Limits = [0 Inf];
            app.cGL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cGL_W.Limits = [0 Inf];
            app.cGL_W.ValueDisplayFormat = '%.2f';
            app.text40_5 = uilabel(app.LoadsTab);
            app.text40_5.Tag = 'text40';
            app.text40_5.Text = 'Generic';
            app.cGL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cGL_M.Limits = [0 Inf];
            app.cGL_M.ValueDisplayFormat = '%.2f';
            app.text24_2 = uilabel(app.LoadsTab);
            app.text24_2.Tag = 'text40';
            app.text24_2.Text = 'Load application option';
            app.LoadApplypopup = uidropdown(app.LoadsTab);
            app.LoadApplypopup.Items = {'Point load', 'Beam uniform load'};
            app.LoadApplypopup.ItemsData = {'1', '2'};
            app.LoadApplypopup.Tag = 'Supportpopup';
            app.LoadApplypopup.Value = '1';
            app.text35_10 = uilabel(app.LoadsTab);
            app.text35_10.Tag = 'text35';
            app.text35_10.Text = '----------------------------------------------------------------------';
            app.text35_11 = uilabel(app.LoadsTab);
            app.text35_11.Tag = 'text35';
            app.text35_11.Text = '----------------------------------------------------------------------';
            app.MemberModelingTab = uitab(app.TabGroup);
            app.MemberModelingTab.Title = 'Member Modeling';
            app.ColElementpopup = uidropdown(app.MemberModelingTab);
            app.ColElementpopup.Items = {'Elastic Beam-Column', 'Fiber D-based', 'Fiber F-based', 'Fiber F-based (HLB)'};
            app.ColElementpopup.ItemsData = {'1', '2', '3', '4', '5'};
            app.ColElementpopup.ValueChangedFcn = createCallbackFcn(app, @ColElementpopupValueChanged, true);
            app.ColElementpopup.Tag = 'ColElementpopup';
            app.ColElementpopup.Value = '1';
            app.text35_4 = uilabel(app.MemberModelingTab);
            app.text35_4.Tag = 'text35';
            app.text35_4.Text = 'MF Column Elements';
            app.text73_2 = uilabel(app.MemberModelingTab);
            app.text73_2.Tag = 'text73';
            app.text73_2.Text = 'Number of segments along length';
            app.text74_2 = uilabel(app.MemberModelingTab);
            app.text74_2.Tag = 'text74';
            app.text74_2.Text = 'Number of integration points per segment';
            app.text75_2 = uilabel(app.MemberModelingTab);
            app.text75_2.Tag = 'text75';
            app.text75_2.Text = 'Global mid-length imperfection [*L]';
            app.edit65 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit65.Limits = [1 Inf];
            app.edit65.ValueDisplayFormat = '%.0f';
            app.edit65.Value = 8;
            app.edit66 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit66.Limits = [1 Inf];
            app.edit66.ValueDisplayFormat = '%.0f';
            app.edit66.Value = 5;
            app.edit67 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit67.Limits = [0 Inf];
            app.edit67.Value = 0.001;
            app.textP1 = uilabel(app.MemberModelingTab);
            app.textP1.Tag = 'text35';
            app.textP1.Text = 'P computed based on';
            app.textP2 = uilabel(app.MemberModelingTab);
            app.textP2.Tag = 'text75';
            app.textP2.Text = 'Scale factor';
            app.edit68 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit68.Limits = [0 Inf];
            app.edit68.Value = 1;
            app.text444 = uilabel(app.MemberModelingTab);
            app.text444.Tag = 'text40';
            app.text444.Text = 'MF Column P-M Interaction';
            app.text35_6 = uilabel(app.MemberModelingTab);
            app.text35_6.Tag = 'text35';
            app.text35_6.Text = 'Fiber-based Elements';
            app.ColMppopup = uidropdown(app.MemberModelingTab);
            app.ColMppopup.Items = {'Max Pushover Load', 'Scaled Gravity Load'};
            app.ColMppopup.ItemsData = {'1', '2'};
            app.ColMppopup.ValueChangedFcn = createCallbackFcn(app, @ColMppopupValueChanged, true);
            app.ColMppopup.Tag = 'ColElementpopup';
            app.ColMppopup.Value = '1';
            app.text73_3 = uilabel(app.MemberModelingTab);
            app.text73_3.Tag = 'text73';
            app.text73_3.Text = 'Reduction factor for cracked section';
            app.coeff_cracked = uieditfield(app.MemberModelingTab, 'numeric');
            app.coeff_cracked.Limits = [0.01 1];
            app.coeff_cracked.ValueDisplayFormat = '%.2f';
            app.coeff_cracked.Value = 0.5;
            app.text333 = uilabel(app.MemberModelingTab);
            app.text333.Tag = 'text35';
            app.text333.Text = 'RC Members';
            app.bond_slip = uicheckbox(app.MemberModelingTab);
            app.bond_slip.Tag = 'FuseF_ProfileCB';
            app.bond_slip.Text = 'Bond slip is possible';
            app.bond_slip.Value = true;
            app.BeamElementpopup = uidropdown(app.MemberModelingTab);
            app.BeamElementpopup.Items = {'Elastic Beam-Column', 'Fiber D-based', 'Fiber F-based'};
            app.BeamElementpopup.ItemsData = {'1', '2', '3'};
            app.BeamElementpopup.Tag = 'ColElementpopup';
            app.BeamElementpopup.Value = '1';
            app.text351 = uilabel(app.MemberModelingTab);
            app.text351.Tag = 'text35';
            app.text351.Text = 'MF Beam Elements';
            app.Kshearstatus = uicheckbox(app.MemberModelingTab);
            app.Kshearstatus.Tag = 'Kshear_CB';
            app.Kshearstatus.Tooltip = {'This option can be used to modify the moment of inertia of elasticBeamColumn and ModElasticBeam2d to account for the shear flexibility of I-shaped members, which is currently overlooked by these elements.'};
            app.Kshearstatus.Text = 'Account for shear flexibility in elastic beam-column elements';
            app.text35_14 = uilabel(app.MemberModelingTab);
            app.text35_14.Tag = 'text35';
            app.text35_14.Text = '----------------------------------------------------------------------';
            app.text35_15 = uilabel(app.MemberModelingTab);
            app.text35_15.Tag = 'text35';
            app.text35_15.Text = '----------------------------------------------------------------------';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.text52 = uilabel(app.MaterialsTab);
            app.text52.Tag = 'text52';
            app.text52.Text = 'E';
            app.text53 = uilabel(app.MaterialsTab);
            app.text53.Tag = 'text53';
            app.text53.Text = 'fy';
            app.text43 = uilabel(app.MaterialsTab);
            app.text43.Tag = 'text40';
            app.text43.Text = 'Material [ksi]';
            app.E = uieditfield(app.MaterialsTab, 'numeric');
            app.E.Limits = [0 Inf];
            app.E.Value = 29000;
            app.fy = uieditfield(app.MaterialsTab, 'numeric');
            app.fy.Limits = [0 Inf];
            app.fy.Value = 55;
            app.text53_2 = uilabel(app.MaterialsTab);
            app.text53_2.Tag = 'text53';
            app.text53_2.Text = 'μ';
            app.mu0 = uieditfield(app.MaterialsTab, 'numeric');
            app.mu0.Limits = [0 Inf];
            app.mu0.Value = 0.3;
            app.text35_7 = uilabel(app.MaterialsTab);
            app.text35_7.Tag = 'text35';
            app.text35_7.Text = 'Beams & columns';
            app.text35_8 = uilabel(app.MaterialsTab);
            app.text35_8.Tag = 'text35';
            app.text35_8.Text = 'Rebar';
            app.text35_9 = uilabel(app.MaterialsTab);
            app.text35_9.Tag = 'text35';
            app.text35_9.Text = 'Concrete';
            app.Materialpopup = uidropdown(app.MaterialsTab);
            app.Materialpopup.Items = {'User-Defined', 'Generic Gr. 50', 'A992 Gr. 50', 'S355J2+N plate', 'S355J2+N section', 'S460NL', 'S690QL', 'A500 Gr. B', 'BCP325', 'BCR295', 'HYP400'};
            app.Materialpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.Materialpopup.ValueChangedFcn = createCallbackFcn(app, @MaterialpopupValueChanged, true);
            app.Materialpopup.Tag = 'Supportpopup';
            app.Materialpopup.Value = '1';
            app.fyR = uieditfield(app.MaterialsTab, 'numeric');
            app.fyR.Limits = [0 Inf];
            app.fyR.Value = 55;
            app.Er = uieditfield(app.MaterialsTab, 'numeric');
            app.Er.Limits = [0 Inf];
            app.Er.Value = 29000;
            app.muR = uieditfield(app.MaterialsTab, 'numeric');
            app.muR.Limits = [0 Inf];
            app.muR.Value = 0.3;
            app.text53_5 = uilabel(app.MaterialsTab);
            app.text53_5.Tag = 'text53';
            app.text53_5.Text = 'fc';
            app.fc = uieditfield(app.MaterialsTab, 'numeric');
            app.fc.Limits = [0 Inf];
            app.fc.Value = 5;
            app.Ec = uieditfield(app.MaterialsTab, 'numeric');
            app.Ec.Limits = [0 Inf];
            app.Ec.Value = 2600;
            app.muC = uieditfield(app.MaterialsTab, 'numeric');
            app.muC.Limits = [0 Inf];
            app.muC.Value = 0.2;
            app.fyBrace = uieditfield(app.MaterialsTab, 'numeric');
            app.fyBrace.Limits = [0 Inf];
            app.fyBrace.Enable = 'off';
            app.fyBrace.Value = 55;
            app.fyGP = uieditfield(app.MaterialsTab, 'numeric');
            app.fyGP.Limits = [0 Inf];
            app.fyGP.Enable = 'off';
            app.fyGP.Value = 55;
            app.text35_12 = uilabel(app.MaterialsTab);
            app.text35_12.Tag = 'text35';
            app.text35_12.Text = 'Gusset plate';
            app.text35_13 = uilabel(app.MaterialsTab);
            app.text35_13.Tag = 'text35';
            app.text35_13.Text = 'Brace';
            app.text53_6 = uilabel(app.MaterialsTab);
            app.text53_6.Tag = 'text53';
            app.text53_6.Text = 'fu';
            app.fyP = uieditfield(app.MaterialsTab, 'numeric');
            app.fyP.Limits = [0 Inf];
            app.fyP.Enable = 'off';
            app.fyP.Value = 55;
            app.text35_16 = uilabel(app.MaterialsTab);
            app.text35_16.Tag = 'text35';
            app.text35_16.Text = 'Endplate';
            app.fuP = uieditfield(app.MaterialsTab, 'numeric');
            app.fuP.Limits = [0 Inf];
            app.fuP.Enable = 'off';
            app.fuP.Value = 65;
            app.fu = uieditfield(app.MaterialsTab, 'numeric');
            app.fu.Limits = [0 Inf];
            app.fu.Value = 65;
            app.fyb = uieditfield(app.MaterialsTab, 'numeric');
            app.fyb.Limits = [0 Inf];
            app.fyb.Enable = 'off';
            app.fyb.Value = 130;
            app.text35_17 = uilabel(app.MaterialsTab);
            app.text35_17.Tag = 'text35';
            app.text35_17.Text = 'Bolt';
            app.fub = uieditfield(app.MaterialsTab, 'numeric');
            app.fub.Limits = [0 Inf];
            app.fub.Enable = 'off';
            app.fub.Value = 145;
            app.text53_7 = uilabel(app.MaterialsTab);
            app.text53_7.Tag = 'text53';
            app.text53_7.Text = 'μ';
            app.text52_2 = uilabel(app.MaterialsTab);
            app.text52_2.Tag = 'text52';
            app.text52_2.Text = 'E';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Build_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Build_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end