classdef FM2D_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        UnlockButton        matlab.ui.control.Button
        Image               matlab.ui.control.Image
        HelpButton          matlab.ui.control.Button
        RegisterButton      matlab.ui.control.Button
        DevelopersButton    matlab.ui.control.Button
        status0_2           matlab.ui.control.Label
        AboutButton         matlab.ui.control.Button
        ResetButton         matlab.ui.control.Button
        Image2              matlab.ui.control.Image
        ModelButton         matlab.ui.control.Button
        status0             matlab.ui.control.Label
        status0b            matlab.ui.control.Label
        BuildingButton      matlab.ui.control.Button
        AnalysisButton      matlab.ui.control.Button
        BuildtclfileButton  matlab.ui.control.Button
        status2             matlab.ui.control.Label
        status2b            matlab.ui.control.Label
        status1             matlab.ui.control.Label
        status1b            matlab.ui.control.Label
        RunModelButton      matlab.ui.control.Button
        GetperiodButton     matlab.ui.control.Button
        uipanel1            matlab.ui.container.Panel
        ScopeButton         matlab.ui.control.Button
        SaveAsButton        matlab.ui.control.Button
        text1               matlab.ui.control.Label
        OpenButton          matlab.ui.control.Button
        NewButton           matlab.ui.control.Button
        VisualizeButton     matlab.ui.control.Button
        ProgressText        matlab.ui.control.Label
    end


    methods (Access = private)

        function BLINK_TEXT(app, YourString, pauseTime, nTimes)
            app.ProgressText.FontColor=[0 1 0];
            app.ProgressText.Text=YourString;
            drawnow;

            for i=1:nTimes
                app.ProgressText.Text='';
                drawnow;
                pause(pauseTime);
                app.ProgressText.Text=YourString;
                drawnow;
                pause(pauseTime);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FM2D_OpeningFcn(app, varargin)
            global MainDirectory resource_root Private
            clc; clear ProjectPath ProjectName;
            MainDirectory=pwd;

            resource_root="resources\images\";
            %resource_root="";
            Private="No";

            if   resource_root==''; [y, Fs] = audioread(strjoin(resource_root+"sound-start.mp3"));    sound(y,Fs);
            else                    [y, Fs] = audioread(strjoin("resources\sounds\sound-start.mp3")); sound(y,Fs); end

            app.Image.ImageSource  = strjoin(resource_root+"Transparent.png");
            app.Image2.ImageSource = strjoin(resource_root+"Banner.png");
            app.UnlockButton.Icon  = strjoin(resource_root+"lock.png");
            app.ScopeButton.Icon   = strjoin(resource_root+"scope.png");
            app.figure1.Icon       = strjoin(resource_root+"icon.png");

            app.text1.Text                  = 'Project: --';

            app.NewButton.Enable            = 'on';
            app.OpenButton.Enable           = 'on';
            app.SaveAsButton.Enable         = 'off';
            app.ScopeButton.Enable          = 'off';

            app.ModelButton.Enable          = 'off';
            app.BuildingButton.Enable       = 'off';
            app.AnalysisButton.Enable       = 'off';

            app.GetperiodButton.Enable      = 'off';
            app.BuildtclfileButton.Enable   = 'off';
            app.RunModelButton.Enable       = 'off';
            app.VisualizeButton.Enable      = 'off';

            app.status0.BackgroundColor  = 'r';
            app.status0.FontColor        = 'w';
            app.status0.Text             = 'status: not defined';
            app.status0b.BackgroundColor = 'r';
            app.status1.BackgroundColor  = 'r';
            app.status1.FontColor        = 'w';
            app.status1.Text             = 'status: not defined';
            app.status1b.BackgroundColor = 'r';
            app.status2.BackgroundColor  = 'r';
            app.status2.FontColor        = 'w';
            app.status2.Text             = 'status: not defined';
            app.status2b.BackgroundColor = 'r';

            app.Image.Visible='off';

            BLINK_TEXT (app,'© A. Elkady, University of Southampton 2020', 0.2, 2);

        end

        % Button pushed function: AnalysisButton
        function AnalysisButton_Callback(app, event)
            app.ProgressText.FontColor='g';
            app.ProgressText.Text=('Defining analysis data...'); drawnow;
            Analysis_App(app);
        end

        % Button pushed function: BuildingButton
        function BuildingButton_Callback(app, event)
            app.ProgressText.FontColor='g';
            app.ProgressText.Text=('Defining building data...'); drawnow;
            Build_App(app);
        end

        % Button pushed function: OpenButton
        function OpenButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName resource_root
            clc;

            % Get current directory, the user-specified project name, and selected Units
            MainDirectory=pwd;
            [ProjectName,ProjectPath] = uigetfile('*.mat','Select the Project .mat file');

            if ProjectName==0; return; end

            cd(MainDirectory)

            % Get current directory and user-specified project name
            if exist(ProjectPath) ==0
                d=errordlg('Project does not exist in current directory!','Error','modal');
                uiwait(d);
            else

                try
                    load(strcat(ProjectPath,ProjectName),'PROJECT');
                    if PROJECT.Version < 5
                        errordlg('Projects created by v4 or earlier versions are not supported by v5!. Please consider re-defining your project in the current version.', 'Incompatible version');
                        return
                    end
                end

                load(strcat(ProjectPath,ProjectName),'PROJECT','ANALYSIS','STATUS','MODEL','CONNECTION','LAYOUT');

                PROJECT.Version        = 5.260701;
                PROJECT.MainDirectory  = MainDirectory;
                PROJECT.ProjectPath    = ProjectPath;
                PROJECT.ProjectName    = ProjectName;
                PROJECT.resource_root  = resource_root;
                save(strcat(ProjectPath,ProjectName),'PROJECT','resource_root','-append');
                v2struct(PROJECT)
                v2struct(ANALYSIS)

                if exist(RFpath,'dir')==0;     RFpath=ProjectPath; end
                if strcmp(RFpath(end),'\')==1; RFpath(end)=[]; end

                PROJECT.RFpath         = RFpath;

                if isfield(CONNECTION,'GFconnection') ==0
                    CONNECTION.GFconnection=1;
                end

                save(strcat(ProjectPath,ProjectName),'PROJECT','CONNECTION','-append');

                if EQ==1 && exist(GMFolderPath,'dir')==0; warndlg('The GM folder path does not exist! Please ammend from the Analysis Parameters module'); end

                app.Image.Visible                   = 'off';
                app.SaveAsButton.Enable             = 'on';
                app.ModelButton.Enable              = 'on';
                app.ScopeButton.Enable              = 'on';


                if STATUS.Model==1
                    v2struct(MODEL)
                    if ModelELOption==1; s0='Nonlinear-'; else; s0='Linear-'; end
                    if CompositeX==1;    s1='C';          else; s1='B';       end
                    if GFX==1;           s2='G';          else; s2='';        end
                    Typestr=[s0,s1,s2];

                    app.status0.BackgroundColor     = 'g';
                    app.status0.FontColor           = 'k';
                    app.status0.Text                = Typestr;
                    app.status0b.BackgroundColor    = 'g';
                    app.BuildingButton.Enable       = 'on';
                    clear s0 s1 s2 Typestr
                end

                if STATUS.Build==1
                    v2struct(LAYOUT)
                    app.status1.BackgroundColor = 'g';
                    app.status1.FontColor       = 'k';
                    if PROJECT.FrameType==1
                        app.status1.Text=[num2str(LAYOUT.NStory),'-story MRF'];
                    elseif PROJECT.FrameType==2
                        if BraceLayout==1; app.status1.Text=[num2str(LAYOUT.NStory),'-story CBF - X-Brace']; end
                        if BraceLayout==2; app.status1.Text=[num2str(LAYOUT.NStory),'-story CBF - Chevron']; end
                    elseif PROJECT.FrameType==3
                        app.status1.Text=[num2str(LAYOUT.NStory),'-story EBF'];
                    elseif PROJECT.FrameType==4
                        app.status1.Text=[num2str(LAYOUT.NStory),'-story RC MRF'];
                    end
                    app.status1b.BackgroundColor    = 'g';
                    app.AnalysisButton.Enable       = 'on';
                    app.GetperiodButton.Enable      = 'on';
                    app.BuildtclfileButton.Enable   = 'on';
                end


                if STATUS.Analysis==1
                    v2struct(ANALYSIS)
                    app.status2.BackgroundColor  = 'g';
                    app.status2.FontColor        = 'k';
                    if     ANALYSIS.IDA==1;              app.status2.Text='Dynamic: IDA';
                    elseif ANALYSIS.Dynamic_TargetSA==1; app.status2.Text='Dynamic: Target Sa';
                    elseif ANALYSIS.Dynamic_TargetSF==1; app.status2.Text='Dynamic: Target SF';
                    elseif ANALYSIS.PO==1;               app.status2.Text='Pushover Analysis';
                    elseif ANALYSIS.EV==1;               app.status2.Text='Eigenvalue Analysis';
                    elseif ANALYSIS.ELF==1;              app.status2.Text='ELF Analysis';
                    elseif ANALYSIS.CDPO==1;             app.status2.Text='CDPO Analysis';
                    elseif ANALYSIS.TTH==1;              app.status2.Text='TTH Analysis';           end
                    app.status2b.BackgroundColor = 'g';
                    app.RunModelButton.Enable    = 'on';
                end

                if STATUS.Run==1
                    app.VisualizeButton.Enable = 'on';
                else
                    app.VisualizeButton.Enable = 'off';
                end

                app.ProgressText.FontColor='g';
                app.ProgressText.Text=('Project loaded correctly'); drawnow;
            end

            app.text1.Text=ProjectName(1:end-4);

        end

        % Button pushed function: SaveAsButton
        function SaveAsButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName

            try

                load (strcat(ProjectPath,ProjectName));
                MainDirectory2=pwd;

                % Get current directory, the user-specified project name, and selected Units
                [ProjectName2, ProjectPath2] = uiputfile('*.mat','Save current project as');

                xx=ProjectName2;
                xx=xx(1:end-4);
                xx1= xx(~isspace(xx)); % remove spaces from project name to get opensees file name
                OpenSEESFileName = [xx1,'.tcl'];
                clear xx xx1

                PROJECT.OpenSEESFileName=OpenSEESFileName;
                PROJECT.MainDirectory=MainDirectory2;
                PROJECT.ProjectName=ProjectName2;
                PROJECT.ProjectPath=ProjectPath2;

                clear MainDirectory2 ProjectName2 ProjectPath2 OpenSEESFileName MainDirectory ProjectPath ProjectName

                save(strcat(PROJECT.ProjectPath,PROJECT.ProjectName), '-regexp', '^(?!(event|app)$).','-v7.3','-nocompression');

                MainDirectory = PROJECT.MainDirectory;
                ProjectPath   = PROJECT.ProjectPath;
                ProjectName   = PROJECT.ProjectName;

                app.text1.Text=PROJECT.ProjectName(1:end-4);

            catch ME

                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor  = [1 0 0];
                app.ProgressText.Text       = 'Error has occured! Please see Error file!'; drawnow;
                app.Image.Visible           = 'off';

            end
        end

        % Button pushed function: NewButton
        function NewButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath Units FrameType BuildingDescription

            FM2D_OpeningFcn(app);

            MainDirectory = pwd;

            [ProjectName, ProjectPath] = uiputfile('*.mat','Select the workspace direcotry and specify project name');

            if  isempty(ProjectName) || ischar(ProjectName)==0; return; end

            Units       = 0;
            FrameType   = 0;
            waitfor(TypeMessage_App(app));

            if Units==0 || FrameType==0; return; end

            initialize_Project;

            app.text1.Text=ProjectName(1:end-4);

            app.SaveAsButton.Enable   = 'on';
            app.ScopeButton.Enable    = 'on';
            app.ModelButton.Enable    = 'on';
            app.Image.Visible         = 'off';

        end

        % Button pushed function: VisualizeButton
        function VisualizeButton_Callback(app, event)
            Visualize_App(app)
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath resource_root
            load(strcat(ProjectPath,ProjectName))

            clc; fclose all;

            StartTime = tic;    % start watch for analysis runtime

            app.Image.Visible           = 'on';
            app.ProgressText.FontColor  = [1 1 0];

            if STATUS.Run == 1; waitfor(ExecutionMessage_App(app)); end

            try

                if ANALYSIS.IDA==1 || ANALYSIS.Dynamic_TargetSA ==1 || ANALYSIS.Dynamic_TargetSF==1
                    app.ProgressText.Text  = 'Computing Sa for the ground motion records ...'; drawnow;
                    for GM_No = ANALYSIS.GM_Start : ANALYSIS.GM_Last
                        %% GET IM FOR SCALING
                        [Sa] = get_IM (ANALYSIS.T1, ANALYSIS.GM.acc{:,GM_No}, ANALYSIS.GM.dt{:,GM_No}, ANALYSIS.g, ANALYSIS.zetaSA, ANALYSIS.SA_metric, ANALYSIS.coeff_T_lower, ANALYSIS.coeff_T_upper);
                        ANALYSIS.GM.sa{:,GM_No}=Sa;
                    end
                    clear GM_No;
                    save(strcat(ProjectPath,ProjectName),'ANALYSIS','-append')
                end

                app.ProgressText.Text  = 'Consolidating variables ...'; drawnow;

                delete temp_unpacked.mat
                
                consolidate_vars;

                ExecutionOption = 1;

                if ANALYSIS.Parallel==0
                    Execution_Commands    (app, PROJECT.FrameType, ExecutionOption, PROJECT.OpenSEESFileName, PROJECT.RFpath, ANALYSIS.EV, ANALYSIS.PO, ANALYSIS.EQ, ANALYSIS.ELF, ANALYSIS.CDPO, ANALYSIS.TTH, MODEL.PM_Option, LAYOUT.BraceLayout);
                else
                    Execution_Commands_par(app, PROJECT.FrameType, ExecutionOption, PROJECT.OpenSEESFileName, PROJECT.RFpath, ANALYSIS.EV, ANALYSIS.PO, ANALYSIS.EQ, ANALYSIS.ELF, ANALYSIS.CDPO, ANALYSIS.TTH, MODEL.PM_Option, LAYOUT.BraceLayout);
                    if  app.ProgressText.Text == "Parallel pool error! Deactivate parallel computing."; return; end
                end

                STATUS.Run = 1;

                save(strcat(ProjectPath,ProjectName),'STATUS','-append')

                app.VisualizeButton.Enable = 'on';
                app.Image.Visible          = 'off';

                ElapsedTime = toc(StartTime); % stop watch for analysis runtime

                if   resource_root==''; [y, Fs] = audioread(strjoin(resource_root+"sound-done.mp3"));    sound(y,Fs);
                else                    [y, Fs] = audioread(strjoin("resources\sounds\sound-done.mp3")); sound(y,Fs); end

                app.ProgressText.FontColor  = [0 1 0];
                app.ProgressText.Text       = ['DONE! Analysis time is ',num2str(round((ElapsedTime/60.0)*10)/10),' minutes']; drawnow;

            catch ME
                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor  = [1 0 0];
                app.ProgressText.Text       = 'Error has occured! Please see Error file!'; drawnow;
                app.Image.Visible           = 'off';
            end


        end

        % Button pushed function: GetperiodButton
        function GetperiodButtonPushed(app, event)

            global MainDirectory ProjectName ProjectPath
            load(strcat(ProjectPath,ProjectName),'PROJECT')
            v2struct(PROJECT)

            clc; fclose all;

            consolidate_vars;

            BuildOption = 2;
            save(strcat(MainDirectory,'\temp_unpacked'),'BuildOption','-append');

            if Units==1
                convfactor  = 1000;
                massUnit    = ' kN.sec2/m';
                forceUnit   = ' kN';
            else
                convfactor  = 12;
                massUnit    = ' kip.sec2/ft';
                forceUnit   = ' kip';
            end


            try
                app.Image.Visible           = 'on';
                app.ProgressText.FontColor  = [1 1 0];
                app.ProgressText.Text       = 'Running model ...'; drawnow;

                [MassTot, Ws, ~] = get_Weight_and_Mass;

                [T1_computed]    = get_Period;

                fclose all;
                delete ('TempModel.tcl');
                delete (strcat(MainDirectory,'\temp_unpacked.mat'));

                app.ProgressText.FontColor  = [0 1 0];
                app.ProgressText.Text       = (['T1=',num2str(round(T1_computed*100)/100),'sec','   ','Mass=',num2str(round(MassTot*convfactor)),massUnit,'   ','Ws=',num2str(round(Ws)),forceUnit]);   drawnow;
                app.Image.Visible           = 'off';
            catch ME
                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor  = [1 0 0];
                app.ProgressText.Text       = 'Error has occured! Please see Error file!'; drawnow;
                app.Image.Visible           = 'off';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clc; fclose all;
            app.Image.Visible='off';
            FM2D_OpeningFcn(app)
            BLINK_TEXT (app,'© A. Elkady, University of Southampton 2020', 0.2, 2)
        end

        % Button pushed function: ScopeButton
        function ScopeButtonPushed(app, event)
            [SummaryText]=get_Project_Summary;
            Project_Summary_App(SummaryText);
        end

        % Button pushed function: BuildtclfileButton
        function BuildtclfileButtonPushed(app, event)
            global MainDirectory

            try
                app.ProgressText.FontColor=[0 1 0];
                app.ProgressText.Text='Creating tcl File...'; drawnow;

                consolidate_vars;

                build_TCL_file(app)

            catch ME
                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor  = [1 0 0];
                app.ProgressText.Text       = 'Error has occured! Please see Error file!'; drawnow;
                app.Image.Visible           = 'off';
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            About_App;
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            app.ProgressText.FontColor  = 'g';
            app.ProgressText.Text       = 'Defining model data...'; drawnow;
            Model_App(app);
        end

        % Button pushed function: DevelopersButton
        function DevelopersButtonPushed(app, event)
            Developers_App;
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            web('https://forms.gle/qLNwgGFS5vbCxmHB7','-new');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            global resource_root

            if   resource_root==''; filex = strjoin(resource_root+"FM-2D Manual.pdf");
            else                    filex = strjoin("resources\FM-2D Manual.pdf"); end

            if ispc;  winopen(filex);           end
            if ismac; system(['open ' filex ]); end
        end

        % Button pushed function: UnlockButton
        function UnlockButtonPushed(app, event)
            app.Image.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FM-2D Main Console';
            app.figure1.Tag = 'figure1';
            app.ProgressText = uilabel(app.figure1);
            app.ProgressText.Tag = 'text2';
            app.ProgressText.Text = '© Ahmed Elkady, University of Southampton, 2020';
            app.VisualizeButton = uibutton(app.figure1, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButton_Callback, true);
            app.VisualizeButton.Tag = 'VisualizeButton';
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.NewButton = uibutton(app.uipanel1, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButton_Callback, true);
            app.NewButton.Tag = 'StartButton';
            app.NewButton.Tooltip = 'Start a new project';
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.uipanel1, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_Callback, true);
            app.OpenButton.Tag = 'OpenButton';
            app.OpenButton.Tooltip = 'Open an existing project';
            app.OpenButton.Text = 'Open';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text27';
            app.text1.Text = 'Project: --';
            app.SaveAsButton = uibutton(app.uipanel1, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButton_Callback, true);
            app.SaveAsButton.Tag = 'SaveAsButton';
            app.SaveAsButton.Enable = 'off';
            app.SaveAsButton.Tooltip = 'Save current project under a different name';
            app.SaveAsButton.Text = 'Save as';
            app.ScopeButton = uibutton(app.uipanel1, 'push');
            app.ScopeButton.ButtonPushedFcn = createCallbackFcn(app, @ScopeButtonPushed, true);
            app.ScopeButton.Icon = 'scope.png';
            app.ScopeButton.Tooltip = 'Show summary of the defined project paramters';
            app.ScopeButton.Text = 'Scope';
            app.GetperiodButton = uibutton(app.figure1, 'push');
            app.GetperiodButton.ButtonPushedFcn = createCallbackFcn(app, @GetperiodButtonPushed, true);
            app.GetperiodButton.Tag = 'DefineModelButton';
            app.GetperiodButton.Enable = 'off';
            app.GetperiodButton.Text = 'Get period';
            app.RunModelButton = uibutton(app.figure1, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Tag = 'DefineModelButton';
            app.RunModelButton.Enable = 'off';
            app.RunModelButton.Text = 'Run Model';
            app.status1b = uilabel(app.figure1);
            app.status1b.Text = '';
            app.status1 = uilabel(app.figure1);
            app.status1.Text = 'status: not defined';
            app.status2b = uilabel(app.figure1);
            app.status2b.Text = '';
            app.status2 = uilabel(app.figure1);
            app.status2.Text = 'status: not defined';
            app.BuildtclfileButton = uibutton(app.figure1, 'push');
            app.BuildtclfileButton.ButtonPushedFcn = createCallbackFcn(app, @BuildtclfileButtonPushed, true);
            app.BuildtclfileButton.Tag = 'DefineModelButton';
            app.BuildtclfileButton.Enable = 'off';
            app.BuildtclfileButton.Text = 'Build tcl file';
            app.AnalysisButton = uibutton(app.figure1, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButton_Callback, true);
            app.AnalysisButton.Tag = 'AnalysisButton';
            app.AnalysisButton.Text = {'Analysis'; 'Parameters'};
            app.BuildingButton = uibutton(app.figure1, 'push');
            app.BuildingButton.ButtonPushedFcn = createCallbackFcn(app, @BuildingButton_Callback, true);
            app.BuildingButton.Tag = 'DefineModelButton';
            app.BuildingButton.Text = {'Building'; 'Parameters'};
            app.status0b = uilabel(app.figure1);
            app.status0b.Text = '';
            app.status0 = uilabel(app.figure1);
            app.status0.Text = 'status: not defined';
            app.ModelButton = uibutton(app.figure1, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Tag = 'DefineModelButton';
            app.ModelButton.Text = {'Model'; 'Parameters'};
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Banner.png');
            app.ResetButton = uibutton(app.figure1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = 'R';
            app.AboutButton = uibutton(app.figure1, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Tooltip = {''};
            app.AboutButton.Text = 'about';
            app.status0_2 = uilabel(app.figure1);
            app.status0_2.Text = 'v5.2607.01';
            app.DevelopersButton = uibutton(app.figure1, 'push');
            app.DevelopersButton.ButtonPushedFcn = createCallbackFcn(app, @DevelopersButtonPushed, true);
            app.DevelopersButton.Text = 'developers';
            app.RegisterButton = uibutton(app.figure1, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'register';
            app.HelpButton = uibutton(app.figure1, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {''};
            app.HelpButton.Text = 'help';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'Transparent.png';
            app.UnlockButton = uibutton(app.figure1, 'push');
            app.UnlockButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockButtonPushed, true);
            app.UnlockButton.Tooltip = {'Unfreeze the console in case a job/run is interrupted '};
            app.UnlockButton.Text = '';
            app.UnlockButton.Icon = fullfile(pathToMLAPP, 'lock.png');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FM2D_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)FM2D_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end