classdef Model_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        ModelingOptionsTab   matlab.ui.container.Tab
        ModelDiscritization  matlab.ui.control.DropDown
        text25               matlab.ui.control.Label
        text6                matlab.ui.control.Label
        text5                matlab.ui.control.Label
        text56_5             matlab.ui.control.Label
        text56_4             matlab.ui.control.Label
        edit53               matlab.ui.control.NumericEditField
        edit52               matlab.ui.control.NumericEditField
        text56_3             matlab.ui.control.Label
        edit51               matlab.ui.control.NumericEditField
        text56_2             matlab.ui.control.Label
        edit50               matlab.ui.control.NumericEditField
        text56               matlab.ui.control.Label
        TipIcon5             matlab.ui.control.Button
        TipIcon4             matlab.ui.control.Button
        TipIcon3             matlab.ui.control.Button
        TipIcon2             matlab.ui.control.Button
        Orientationpopup     matlab.ui.control.DropDown
        checkbox5            matlab.ui.control.CheckBox
        text67               matlab.ui.control.Label
        ModelTypepopup       matlab.ui.control.DropDown
        Transformationpopup  matlab.ui.control.DropDown
        text66               matlab.ui.control.Label
        checkbox4            matlab.ui.control.CheckBox
        checkbox3            matlab.ui.control.CheckBox
        checkbox1            matlab.ui.control.CheckBox
        FilesFoldersTab      matlab.ui.container.Tab
        editRF               matlab.ui.control.EditField
        BrowseRFButton       matlab.ui.control.Button
        text40_5             matlab.ui.control.Label
        edit28               matlab.ui.control.EditField
        text37_2             matlab.ui.control.Label
        SubmitButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        TipWindow;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Model_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';  
            
            global  ProjectName ProjectPath
            clc;
            load(strcat(ProjectPath,ProjectName))
            
            if Units==1
                app.text5.Text='mm';
                app.text6.Text='mm';
            else
                app.text5.Text='in';
                app.text6.Text='in';
            end
            
            if Definestatus~=1
                app.checkbox4.Value=1;
                app.checkbox5.Value=1;
                app.edit28.Value=OpenSEESFileName;
                xx=ProjectPath;
                if strcmp(xx(end),'\')==1; xx(end)=[]; end
                app.editRF.Value=xx;

                app.Transformationpopup.Value='2';
                app.ModelTypepopup.Value='1';

                if Units==1
                    app.edit52.Value=3.5*25.4;
                    app.edit53.Value=4.0*25.4;
                else
                    app.edit52.Value=3.5;
                    app.edit53.Value=4.0;
                end
                
            else
                app.ModelTypepopup.Value=num2str(ModelELOption);
                app.Transformationpopup.Value=num2str(TransformationX);
                app.Orientationpopup.Value=num2str(Orientation);
                app.ModelDiscritization.Value=num2str(DiscritizationOption);
            
                app.checkbox1.Value=CompositeX;
                app.checkbox3.Value=GFX;
                app.checkbox4.Value=PZ_Multiplier;
                app.checkbox5.Value=RigidFloor;

                if strcmp(RFpath(end),'\')==1; RFpath(end)=[]; end
                app.editRF.Value=RFpath;
                app.edit28.Value=OpenSEESFileName;

                app.edit50.Value=Comp_I;
                app.edit51.Value=Comp_I_GC;
                app.edit52.Value=trib;
                app.edit53.Value=ts;
            end
            
            if FrameType==4
                app.checkbox1.Enable="off";
                app.checkbox4.Enable="off";
                app.ModelDiscritization.Visible="on";
            else
                app.ModelDiscritization.Visible="off";                
            end
            
            checkbox1ValueChanged(app);
            checkbox3_Callback(app);

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            load(strcat(ProjectPath,ProjectName))
            
            ModelELOption        = str2double(app.ModelTypepopup.Value);
            TransformationX      = str2double(app.Transformationpopup.Value);
            Orientation          = str2double(app.Orientationpopup.Value);
            DiscritizationOption = str2double(app.ModelDiscritization.Value);

            CompositeX    = app.checkbox1.Value;
            GFX           = app.checkbox3.Value;
            PZ_Multiplier = app.checkbox4.Value;
            RigidFloor    = app.checkbox5.Value;

            if ModelELOption==1; s0='NonLinear-'; else s0='Linear-'; end
            if CompositeX==1; s1='C'; else s1='B'; end
            if GFX==1;        s2='G'; else s2='';  end
            Typestr=[s0,s1,s2];    
            clear s0 s1 s2

            Comp_I      = app.edit50.Value;
            Comp_I_GC   = app.edit51.Value;
            trib        = app.edit52.Value;
            ts          = app.edit53.Value;
            
            if CompositeX   == 0
                Comp_I      = 1;
                Comp_I_GC   = 1;
            end

            OpenSEESFileName = app.edit28.Value;
            if isspace(OpenSEESFileName)==1; errordlg('OpenSEES filename should not include spaces!'); return; end
            
            RFpath = app.editRF.Value;

            if ModelELOption==1
                EL_Multiplier=1;
            else
                EL_Multiplier=100;
            end
                 
            if exist ('OpenSEESFileName')==0 || isempty ('OpenSEESFileName')==1
                errordlg('Please specify the name of the OpenSEES tcl file!');
                return;
            end
            if exist ('RFpath')==0 || isempty ('RFpath')==1
                errordlg('Please specify the location of the Results folder!');
                return;
            end
            
            if RFpath(end)=='\' || length(RFpath)<=3; 
                %RFpath(end)=[]; 
                d=errordlg('Please use a folder and not a root drive for the results directory!','Error','modal');
                uiwait(d);
                return;
            end

            Modelstatus = 1;
            
            % Save Data to .mat File
            save(strcat(ProjectPath,ProjectName), '-regexp', '^(?!(event|app)$).')

            app.MainApp.status0.BackgroundColor  = 'g';
            app.MainApp.status0.FontColor        = 'k';
            app.MainApp.status0.Text             = Typestr;
            app.MainApp.status0b.BackgroundColor = 'g';
            
            app.MainApp.BuildingButton.Enable    = 'on';
            
            app.MainApp.ProgressText.FontColor='g';
            app.MainApp.ProgressText.Text=('Model data defined'); drawnow;

            fclose all;
            
            figure1CloseRequest(app)
        end

        % Value changed function: checkbox3
        function checkbox3_Callback(app, event)
            if app.checkbox3.Value == 1
                app.Orientationpopup.Enable='on';
            else
                app.Orientationpopup.Enable='off';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible            = 'off';
            delete(app)
        end

        % Button pushed function: BrowseRFButton
        function BrowseRFButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            clc

            RFpath= uigetdir;
            if RFpath(end)=='\'; 
                %RFpath(end)=[]; 
                d=errordlg('Please use a folder and not a root drive for the results directory!','Error','modal');
                uiwait(d);
                return;
            end
            app.editRF.Value=RFpath;
            save (strcat(ProjectPath,ProjectName),'RFpath','-append')

        end

        % Button pushed function: TipIcon2
        function TipIcon2ButtonPushed(app, event)
            str{1}='Consider the strength and stiffness contributions of the gravity framing system';
            str{2}='';
            str{3}='Refer to: ';
            str{4}='----------';
            str{5}='- Elkady & Lignos (2015), Earthquake Eng. & Structural Dynamics: 44(8)';            
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;            
        end

        % Button pushed function: TipIcon3
        function TipIcon3ButtonPushed(app, event)
            str{1}='Consider the composite action effect, provided by the concrete-on-steel-deck floor slab, on the behaviour (strength, stiffness and ductility) of beam-to-column connections';
            str{2}='';
            str{3}='Refer to: ';
            str{4}='----------';
            str{5}='- Elkady & Lignos (2014), Earthquake Eng. & Structural Dynamics: 43(13)';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;              
        end

        % Button pushed function: TipIcon4
        function TipIcon4ButtonPushed(app, event)
            str{1}='Connect all floor nodes with a rigid body constraint';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;              
        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            str{1}='Consider the column panel zone deformation using the idealized parallelogram model and a trilinear hysteretic model for its shear-force/shear-distortion behaviour.';
            str{2}='';
            str{3}='Refer to: ';
            str{4}='----------';
            str{5}='- Krawinkler (1978), Engineering Journal: 15(3)';
            str{6}='- Gupta & Krawinkler (1999), John A. Blume Center Report No. 132';
            str{7}='- Elkady & Lignos (2014), Earthquake Eng. & Structural Dynamics: 43(13)';
            str{8}='- Elkady & Lignos (2015), Earthquake Eng. & Structural Dynamics: 44(8)';            
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;   
            figure('position',[100 100 450 350],'color','white','Resize','off');
            resource_root = "resources\images\";
            data=imread(strjoin(resource_root+"PZ Model.jpg"));
            imshow(data);            
        end

        % Value changed function: checkbox1
        function checkbox1ValueChanged(app, event)
            if app.checkbox1.Value ==1
                app.edit50.Enable='on';
                app.edit51.Enable='on';
                app.edit52.Enable='on';
                app.edit53.Enable='on';
                app.edit50.Value=1.4;
                app.edit51.Value=1.4;
            else
                app.edit50.Enable='off';
                app.edit51.Enable='off';
                app.edit52.Enable='off';
                app.edit53.Enable='off';                
                app.edit50.Value=1.0;
                app.edit51.Value=1.0;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Model Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'BuildButton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.ModelingOptionsTab = uitab(app.TabGroup);
            app.ModelingOptionsTab.Title = 'Modeling Options';
            app.checkbox1 = uicheckbox(app.ModelingOptionsTab);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1ValueChanged, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Composite Action of the Floor Slab ';
            app.checkbox3 = uicheckbox(app.ModelingOptionsTab);
            app.checkbox3.ValueChangedFcn = createCallbackFcn(app, @checkbox3_Callback, true);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Gravity Framing System';
            app.checkbox4 = uicheckbox(app.ModelingOptionsTab);
            app.checkbox4.Tag = 'checkbox4';
            app.checkbox4.Text = 'Panel Zone Deformation';
            app.text66 = uilabel(app.ModelingOptionsTab);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Geometric Transformation';
            app.Transformationpopup = uidropdown(app.ModelingOptionsTab);
            app.Transformationpopup.Items = {'Linear', 'P-Delta', 'Corotational'};
            app.Transformationpopup.ItemsData = {'1', '2', '3'};
            app.Transformationpopup.Tag = 'Transformationpopup';
            app.Transformationpopup.Value = '1';
            app.ModelTypepopup = uidropdown(app.ModelingOptionsTab);
            app.ModelTypepopup.Items = {'Nonlinear', 'Linear (Elastic)'};
            app.ModelTypepopup.ItemsData = {'1', '2'};
            app.ModelTypepopup.Tag = 'ModelTypepopup';
            app.ModelTypepopup.Value = '1';
            app.text67 = uilabel(app.ModelingOptionsTab);
            app.text67.Tag = 'text67';
            app.text67.Tooltip = {'this refers to the material nonlinearity'};
            app.text67.Text = 'Numerical Model Type';
            app.checkbox5 = uicheckbox(app.ModelingOptionsTab);
            app.checkbox5.Tag = 'checkbox5';
            app.checkbox5.Text = 'Rigid Floor Diaphragm';
            app.Orientationpopup = uidropdown(app.ModelingOptionsTab);
            app.Orientationpopup.Items = {'Weak-axis orientation', 'Strong-axis orientation'};
            app.Orientationpopup.ItemsData = {'1', '2'};
            app.Orientationpopup.Tag = 'Orientationpopup';
            app.Orientationpopup.Tooltip = {'Orientation of the gravity column cross-section with respect to the considered building direction'};
            app.Orientationpopup.Value = '1';
            app.TipIcon2 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon2.ButtonPushedFcn = createCallbackFcn(app, @TipIcon2ButtonPushed, true);
            app.TipIcon2.Text = '?';
            app.TipIcon3 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon3.ButtonPushedFcn = createCallbackFcn(app, @TipIcon3ButtonPushed, true);
            app.TipIcon3.Text = '?';
            app.TipIcon4 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon4.ButtonPushedFcn = createCallbackFcn(app, @TipIcon4ButtonPushed, true);
            app.TipIcon4.Text = '?';
            app.TipIcon5 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.text56 = uilabel(app.ModelingOptionsTab);
            app.text56.Tag = 'text56';
            app.text56.Text = 'Stiffness Coefficients:  MF Beams';
            app.edit50 = uieditfield(app.ModelingOptionsTab, 'numeric');
            app.edit50.Limits = [0 Inf];
            app.edit50.ValueDisplayFormat = '%.2f';
            app.edit50.Value = 1.4;
            app.text56_2 = uilabel(app.ModelingOptionsTab);
            app.text56_2.Tag = 'text56';
            app.text56_2.Text = 'Gravity Beams';
            app.edit51 = uieditfield(app.ModelingOptionsTab, 'numeric');
            app.edit51.Limits = [0 Inf];
            app.edit51.ValueDisplayFormat = '%.2f';
            app.edit51.Value = 1.4;
            app.text56_3 = uilabel(app.ModelingOptionsTab);
            app.text56_3.Tag = 'text56';
            app.text56_3.Text = 'Floor Slab Dimensions: ';
            app.edit52 = uieditfield(app.ModelingOptionsTab, 'numeric');
            app.edit52.Limits = [0 Inf];
            app.edit52.ValueDisplayFormat = '%.2f';
            app.edit52.Value = 3.5;
            app.edit53 = uieditfield(app.ModelingOptionsTab, 'numeric');
            app.edit53.Limits = [0 Inf];
            app.edit53.ValueDisplayFormat = '%.2f';
            app.edit53.Value = 4;
            app.text56_4 = uilabel(app.ModelingOptionsTab);
            app.text56_4.Tag = 'text56';
            app.text56_4.Tooltip = {'Depth of the steel deck rib'};
            app.text56_4.Text = 't_rib = ';
            app.text56_5 = uilabel(app.ModelingOptionsTab);
            app.text56_5.Tag = 'text56';
            app.text56_5.Tooltip = {'Thickness of the concrete slab portion above the steel deck rib'};
            app.text56_5.Text = 't_slab = ';
            app.text5 = uilabel(app.ModelingOptionsTab);
            app.text5.Tag = 'text56';
            app.text5.Text = 'in';
            app.text6 = uilabel(app.ModelingOptionsTab);
            app.text6.Tag = 'text56';
            app.text6.Text = 'in';
            app.text25 = uilabel(app.ModelingOptionsTab);
            app.text25.Tag = 'text40';
            app.text25.Text = 'Modeling Considerations';
            app.ModelDiscritization = uidropdown(app.ModelingOptionsTab);
            app.ModelDiscritization.Items = {'Lumped plasticity', 'Fiber-based'};
            app.ModelDiscritization.ItemsData = {'1', '2'};
            app.ModelDiscritization.Tag = 'ModelTypepopup';
            app.ModelDiscritization.Tooltip = {'The method to be used in discritizing the RC frame.'};
            app.ModelDiscritization.Value = '1';
            app.FilesFoldersTab = uitab(app.TabGroup);
            app.FilesFoldersTab.Title = 'Files & Folders';
            app.text37_2 = uilabel(app.FilesFoldersTab);
            app.text37_2.Tag = 'text37';
            app.text37_2.Text = 'OpenSEES File Name';
            app.edit28 = uieditfield(app.FilesFoldersTab, 'text');
            app.edit28.Tag = 'edit28';
            app.edit28.Tooltip = {'File name shall no have any "space" and should end with the extension .tcl'};
            app.text40_5 = uilabel(app.FilesFoldersTab);
            app.text40_5.Tag = 'text40';
            app.text40_5.Text = 'Results Folder Path';
            app.BrowseRFButton = uibutton(app.FilesFoldersTab, 'push');
            app.BrowseRFButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRFButtonPushed, true);
            app.BrowseRFButton.Tag = 'BrowseButton';
            app.BrowseRFButton.Text = 'browse';
            app.editRF = uieditfield(app.FilesFoldersTab, 'text');
            app.editRF.Editable = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Model_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end