classdef Visualize_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        text7_10    matlab.ui.control.Label
        CB100       matlab.ui.control.CheckBox
        text7_9     matlab.ui.control.Label
        CB70        matlab.ui.control.CheckBox
        text7_8     matlab.ui.control.Label
        text7_7     matlab.ui.control.Label
        CB20        matlab.ui.control.CheckBox
        CB10        matlab.ui.control.CheckBox
        editRi      matlab.ui.control.NumericEditField
        text7_6     matlab.ui.control.Label
        EDPtype4    matlab.ui.control.DropDown
        EDPtype3    matlab.ui.control.DropDown
        EDPtype2    matlab.ui.control.DropDown
        EDPtype     matlab.ui.control.DropDown
        CB14        matlab.ui.control.CheckBox
        edit100     matlab.ui.control.EditField
        CB13        matlab.ui.control.CheckBox
        text7_5     matlab.ui.control.Label
        text7_4     matlab.ui.control.Label
        text7_3     matlab.ui.control.Label
        text7_2     matlab.ui.control.Label
        CB11        matlab.ui.control.CheckBox
        CB8         matlab.ui.control.CheckBox
        CB7         matlab.ui.control.CheckBox
        CB6         matlab.ui.control.CheckBox
        edit2       matlab.ui.control.NumericEditField
        edit1       matlab.ui.control.NumericEditField
        text8       matlab.ui.control.Label
        text7       matlab.ui.control.Label
        PlotButton  matlab.ui.control.Button
        CB4         matlab.ui.control.CheckBox
        CB3         matlab.ui.control.CheckBox
        CB2         matlab.ui.control.CheckBox
        CB1         matlab.ui.control.CheckBox
        text3       matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Visualize_Options_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global  ProjectName ProjectPath FrameType
            clc;
            load(strcat(ProjectPath,ProjectName),'GM_Start','GM_Last','PO','EQ','EV','IDA','ELF','CDPO','TTH','Recorders','Delete_Flag','nRealizations')
            
            nGMs = GM_Last-GM_Start+1;

            %             app.edit1.Limits=[1 max(nGMs,2)];
            %             app.edit2.Limits=[1 max(nGMs,2)];
            
            if (PO==1 || ELF==1 || CDPO==1) && Delete_Flag==0
                app.CB1.Enable='on';
                app.CB2.Enable='on';
                app.CB3.Enable='on';
                app.CB6.Enable='on';
                app.CB10.Enable='on';
                app.CB20.Enable='on';
                app.CB100.Enable='on';
            end
            
            if (EQ==1 || TTH==1) && Delete_Flag==0
                app.CB1.Enable='on';
                app.CB2.Enable='on';
                app.CB4.Enable='on';
                app.CB6.Enable='on';
                app.CB10.Enable='on';
                app.CB20.Enable='on';
                app.CB13.Enable='on';
                app.CB100.Enable='on';
            end
            
            if IDA==1
                app.CB7.Enable='on';
                app.CB11.Enable='on';
            end
            
            app.CB8.Enable='on';
            
            if EQ~=1
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit1.Value=1;
                app.edit2.Value=1;
            else
                app.edit1.Enable='on';
                app.edit2.Enable='on';
                app.edit1.Value=GM_Start;
                app.edit2.Value=GM_Last;
            end
            
            if Recorders.Disp~=1
                app.CB2.Enable='off';
            end
            
            if nRealizations>1
                app.CB14.Enable='on';
                app.editRi.Enable='on';
                app.editRi.Limits=[1 nRealizations];
            end
            
            if FrameType==2 ||  FrameType==3
                app.CB6.Enable='off';               
                app.CB10.Enable='off';
                app.CB20.Enable='off';
                app.CB100.Enable='off';
            end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global MainDirectory  ProjectPath ProjectName
            clc
            load(strcat(ProjectPath,ProjectName),'EV','PO','EQ','ELF','CDPO','TTH','Uncertainty','GM','Recorders','nRealizations','RFpath', 'RESULTS','Delete_Flag','Parallel');
            
            app.edit100.FontColor = [1.0 1.0 0.0];
            app.edit100.Value     = 'Please wait while data is processed.'; drawnow;
            
            GMi = app.edit1.Value;
            GMj = app.edit2.Value;
            nGMs = GMj-GMi+1;
            
            if EQ==1 && Delete_Flag==0
                cd (strcat(RFpath,'\Results'));
                EDP_data=importdata('Summary Maximum SDR.txt');
                if size(EDP_data,1)~=nGMs 
                app.edit100.Value     = 'Re-computing summary of max EDPs due to a segmental run. Please wait.'; drawnow;
                Save_Results_Dynamic;
                end
            end

            Ri = app.editRi.Value;
            
            if     EV==1;                     SubRFname = 'EigenAnalysis';                 
            elseif PO==1;                     SubRFname = 'Pushover';                      
            elseif ELF==1;                    SubRFname = 'ELF';                           
            elseif EQ==1 && Uncertainty==0;   if Parallel == 0; SubRFname = GM.name{GMi}; else; SubRFname = [GM.name{GMi},'_1']; end                    
            elseif EQ==1 && Uncertainty==1;   SubRFname = [GM.name{GMi},'_',num2str(Ri)];  
            elseif CDPO==1;                   SubRFname = 'Pushover';                      
            elseif TTH==1;                    SubRFname = 'Tsunami';                       end

            save(strcat(ProjectPath,ProjectName), 'SubRFname', '-append');

            try
                if app.CB8.Value==1
                    Plot_Eigen_Vectors();
                end
                
                if app.CB1.Value==1
                    if EQ==1
                        for GM_No=GMi:GMj
                            Plot_BSF_vs_SDR1(GM_No, Ri);
                        end
                    else
                        Plot_BSF_vs_SDR1(1, Ri);
                        %[X, Y, POC]=Plot_BSF_vs_SDR1(1, Ri);
                        % Plot_Profile_SSF_PO(POC);
                    end
                end
                
                if app.CB2.Value==1
                    if EQ==1
                        for GM_No=GMi:GMj
                            Plot_BSF_vs_RDR(GM_No, Ri);
                        end
                    else
                        Plot_BSF_vs_RDR(1, Ri);
                        %[X, Y, POC]=Plot_BSF_vs_RDR(1, Ri);
                        % Plot_Profile_SSF_PO(POC);
                    end
                end
                
                if app.CB3.Value==1
                    Plot_Profile_Drift;
                end
                
                if app.CB4.Value==1
                    EDP_selected=app.EDPtype.Value;
                    if EDP_selected=='1' && Recorders.SDR==1;    Plot_Profile_SDR(GMi,GMj); end
                    if EDP_selected=='2' && Recorders.SDR==1;    Plot_Profile_RDR(GMi,GMj); end
                    if EDP_selected=='3' && Recorders.RFA==1;    Plot_Profile_PFA(GMi,GMj); end
                    if EDP_selected=='4' && Recorders.RFA==1;    Plot_Profile_PFV(GMi,GMj); end
                    if EDP_selected=='5' && Recorders.Column==1; Plot_Profile_SSF(GMi,GMj); end
                end
                
                if app.CB7.Value==1
                    EDP_selected=app.EDPtype4.Value;
                    if EDP_selected=='1' && Recorders.SDR==1; Plot_IDA(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && Recorders.SDR==1; Plot_IDA(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && Recorders.RFA==1; Plot_IDA(GMi,GMj,'PFA'); end
                end
                
                if app.CB11.Value==1
                    Plot_Collapse_Fragility(GMi,GMj)
                end
                
                if app.CB13.Value==1 && nGMs*nRealizations>=3
                    EDP_selected=app.EDPtype3.Value;
                    if EDP_selected=='1' && Recorders.SDR==1; Plot_EDP_Fragility(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && Recorders.SDR==1; Plot_EDP_Fragility(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && Recorders.RFA==1; Plot_EDP_Fragility(GMi,GMj,'PFA'); end
                elseif app.CB13.Value==1 && nGMs*nRealizations<3
                    app.edit100.Value     = 'Number of GMs must be >=3!'; drawnow;
                end
                
                if app.CB14.Value==1 && nGMs*nRealizations>=3
                    EDP_selected=app.EDPtype2.Value;
                    if EDP_selected=='1' && Recorders.SDR==1; Plot_EDP_vs_Realization(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && Recorders.SDR==1; Plot_EDP_vs_Realization(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && Recorders.RFA==1; Plot_EDP_vs_Realization(GMi,GMj,'PFA'); end
                end

                if app.CB6.Value==1
                    cd (strcat(RFpath,'\Results\', SubRFname));
                    TimeX= importdata('Time.out');
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.CB70.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    end
                    Visualize_Local_App(app)
                end
                
                if app.CB10.Value==1

                    cd (strcat(RFpath,'\Results\', SubRFname));
                    TimeX= importdata('Time.out');
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.CB70.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    end
                    Visualize_Response_Progression_App(app)
                end

                if app.CB20.Value==1

                    if Recorders.ColSpring~=1 || Recorders.BeamSpring~=1
                        errordlg('Data recorders for the beam and column springs must be activated before running analysis!')
                        app.edit100.Value     = ''; drawnow;
                        return
                    end

                    cd (strcat(RFpath,'\Results\', SubRFname));
                    TimeX= importdata('Time.out');
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.CB70.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    end
                    Visualize_PH_Progression_App(app)
                end

                if app.CB100.Value==1

                    if Recorders.ColSpring~=1 || Recorders.BeamSpring~=1
                        errordlg('Data recorders for the beam and column springs must be activated before running analysis!')
                        app.edit100.Value     = ''; drawnow;
                        return
                    end

                    cd (strcat(RFpath,'\Results\', SubRFname));
                    TimeX= importdata('Time.out');
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.CB70.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(ProjectPath,ProjectName), 'RESULTS', '-append');
                    end
                    Visualize_FD_App(app)
                end
                
                app.edit100.FontColor = [0.0 1.0 0.0];
                app.edit100.Value     = 'Plot generated!'; drawnow;
                
            catch ME
                cd(MainDirectory)
                write_Error(ME)
                app.edit100.FontColor = [1.0 0.0 0.0];
                app.edit100.Value     = 'Error occurred. See Error file!'; drawnow;
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app);
        end

        % Value changed function: CB4
        function CB4ValueChanged(app, event)
            if app.CB4.Value==1
                app.EDPtype.Enable='on';
            else
                app.EDPtype.Enable='off';
            end
        end

        % Value changed function: CB14
        function CB14ValueChanged(app, event)
            if app.CB14.Value==1
                app.EDPtype2.Enable='on';
            else
                app.EDPtype2.Enable='off';
            end
        end

        % Value changed function: CB7
        function CB7ValueChanged(app, event)
            if app.CB7.Value==1
                app.EDPtype4.Enable='on';
            else
                app.EDPtype4.Enable='off';
            end
        end

        % Value changed function: CB13
        function CB13ValueChanged(app, event)
            if app.CB13.Value==1
                app.EDPtype3.Enable='on';
            else
                app.EDPtype3.Enable='off';
            end
        end

        % Value changed function: CB6
        function CB6ValueChanged(app, event)
            global  ProjectPath ProjectName
            clc
            load(strcat(ProjectPath,ProjectName),'EQ')
            
            if EQ==1 
                if app.CB6.Value==1
                    app.edit2.Enable="off";
                else
                    app.edit2.Enable="on";                
                end
            end
        end

        % Value changed function: CB20
        function CB20ValueChanged(app, event)
            global  ProjectPath ProjectName
            clc
            load(strcat(ProjectPath,ProjectName),'EQ')
            
            if EQ==1 
                if app.CB20.Value==1
                    app.edit2.Enable="off";
                else
                    app.edit2.Enable="on";                
                end 
            end
            if app.CB20.Value==1
                app.CB70.Enable="on";
            else
                app.CB70.Enable="off";                
            end 
        end

        % Value changed function: CB10
        function CB10ValueChanged(app, event)
            global  ProjectPath ProjectName
            clc
            load(strcat(ProjectPath,ProjectName),'EQ')
            
            if EQ==1 
                if app.CB10.Value==1
                    app.edit2.Enable="off";
                else
                    app.edit2.Enable="on";                
                end     
            end
        end

        % Value changed function: CB100
        function CB100ValueChanged(app, event)
            global  ProjectPath ProjectName
            clc
            load(strcat(ProjectPath,ProjectName),'EQ')
            
            if EQ==1 
                if app.CB100.Value==1
                    app.edit2.Enable="off";
                else
                    app.edit2.Enable="on";                
                end     
            end
    
            if app.CB100.Value==1
                app.CB70.Enable="on";
            else
                app.CB70.Enable="off";                
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Visualization Options';
            app.figure1.Icon = fullfile(pathToMLAPP, 'icon_Visualization.jpg');
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.Panel = uipanel(app.figure1);
            app.text3 = uilabel(app.Panel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Visualization Options';
            app.CB1 = uicheckbox(app.Panel);
            app.CB1.Enable = 'off';
            app.CB1.Text = 'Base shear force vs 1st-story drift ratio';
            app.CB2 = uicheckbox(app.Panel);
            app.CB2.Enable = 'off';
            app.CB2.Text = 'Base shear force vs Roof drift ratio';
            app.CB3 = uicheckbox(app.Panel);
            app.CB3.Enable = 'off';
            app.CB3.Text = 'Pushover floor displacement';
            app.CB4 = uicheckbox(app.Panel);
            app.CB4.ValueChangedFcn = createCallbackFcn(app, @CB4ValueChanged, true);
            app.CB4.Enable = 'off';
            app.CB4.Text = 'Profile plot';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tag = 'BuildButton';
            app.PlotButton.Text = 'Plot';
            app.text7 = uilabel(app.Panel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Ground motion records to plot';
            app.text8 = uilabel(app.Panel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'to';
            app.edit1 = uieditfield(app.Panel, 'numeric');
            app.edit1.Limits = [1 Inf];
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.Panel, 'numeric');
            app.edit2.Limits = [1 Inf];
            app.edit2.ValueDisplayFormat = '%.0f';
            app.edit2.Value = 44;
            app.CB6 = uicheckbox(app.Panel);
            app.CB6.ValueChangedFcn = createCallbackFcn(app, @CB6ValueChanged, true);
            app.CB6.Enable = 'off';
            app.CB6.Text = 'Local member response';
            app.CB7 = uicheckbox(app.Panel);
            app.CB7.ValueChangedFcn = createCallbackFcn(app, @CB7ValueChanged, true);
            app.CB7.Enable = 'off';
            app.CB7.Text = 'IDA curves';
            app.CB8 = uicheckbox(app.Panel);
            app.CB8.Enable = 'off';
            app.CB8.Text = 'Eigen vectors';
            app.CB11 = uicheckbox(app.Panel);
            app.CB11.Enable = 'off';
            app.CB11.Text = 'Collapse fragility Curve';
            app.text7_2 = uilabel(app.Panel);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'Profile Plots';
            app.text7_3 = uilabel(app.Panel);
            app.text7_3.Tag = 'text7';
            app.text7_3.Text = 'IDA Plots';
            app.text7_4 = uilabel(app.Panel);
            app.text7_4.Tag = 'text7';
            app.text7_4.Text = 'Shear-Drift Plots';
            app.text7_5 = uilabel(app.Panel);
            app.text7_5.Tag = 'text7';
            app.text7_5.Text = 'General Plots';
            app.CB13 = uicheckbox(app.Panel);
            app.CB13.ValueChangedFcn = createCallbackFcn(app, @CB13ValueChanged, true);
            app.CB13.Enable = 'off';
            app.CB13.Text = 'EDP fragility';
            app.edit100 = uieditfield(app.Panel, 'text');
            app.edit100.Editable = 'off';
            app.CB14 = uicheckbox(app.Panel);
            app.CB14.ValueChangedFcn = createCallbackFcn(app, @CB14ValueChanged, true);
            app.CB14.Enable = 'off';
            app.CB14.Text = 'EDP vs Realization';
            app.EDPtype = uidropdown(app.Panel);
            app.EDPtype.Items = {'SDR', 'RDR', 'PFA', 'PFV', 'SSF'};
            app.EDPtype.ItemsData = {'1', '2', '3', '4', '5'};
            app.EDPtype.Enable = 'off';
            app.EDPtype.Value = '1';
            app.EDPtype2 = uidropdown(app.Panel);
            app.EDPtype2.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype2.ItemsData = {'1', '2', '3'};
            app.EDPtype2.Enable = 'off';
            app.EDPtype2.Value = '1';
            app.EDPtype3 = uidropdown(app.Panel);
            app.EDPtype3.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype3.ItemsData = {'1', '2', '3'};
            app.EDPtype3.Enable = 'off';
            app.EDPtype3.Value = '1';
            app.EDPtype4 = uidropdown(app.Panel);
            app.EDPtype4.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype4.ItemsData = {'1', '2', '3'};
            app.EDPtype4.Enable = 'off';
            app.EDPtype4.Value = '1';
            app.text7_6 = uilabel(app.Panel);
            app.text7_6.Tag = 'text7';
            app.text7_6.Text = 'Realization';
            app.editRi = uieditfield(app.Panel, 'numeric');
            app.editRi.Limits = [1 Inf];
            app.editRi.ValueDisplayFormat = '%.0f';
            app.editRi.Enable = 'off';
            app.editRi.Value = 1;
            app.CB10 = uicheckbox(app.Panel);
            app.CB10.ValueChangedFcn = createCallbackFcn(app, @CB10ValueChanged, true);
            app.CB10.Enable = 'off';
            app.CB10.Text = 'Local response progression';
            app.CB20 = uicheckbox(app.Panel);
            app.CB20.ValueChangedFcn = createCallbackFcn(app, @CB20ValueChanged, true);
            app.CB20.Enable = 'off';
            app.CB20.Text = 'Plastic hinge progression';
            app.text7_7 = uilabel(app.Panel);
            app.text7_7.Tag = 'text7';
            app.text7_7.Text = '(new)';
            app.text7_8 = uilabel(app.Panel);
            app.text7_8.Tag = 'text7';
            app.text7_8.Text = '(new)';
            app.CB70 = uicheckbox(app.Panel);
            app.CB70.Enable = 'off';
            app.CB70.Tooltip = {'Enforce the re-reading of the .out data files. generally, this is done automatically by the software whenever analysis type changes.'};
            app.CB70.Text = 'Re-read Results';
            app.text7_9 = uilabel(app.Panel);
            app.text7_9.Tag = 'text7';
            app.text7_9.Text = 'Interactive Plots';
            app.CB100 = uicheckbox(app.Panel);
            app.CB100.ValueChangedFcn = createCallbackFcn(app, @CB100ValueChanged, true);
            app.CB100.Enable = 'off';
            app.CB100.Text = 'Force diagrams';
            app.text7_10 = uilabel(app.Panel);
            app.text7_10.Tag = 'text7';
            app.text7_10.Text = '(new)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Visualize_Options_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end