classdef Visualize_Response_Progression_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        CB2                 matlab.ui.control.CheckBox
        CB1                 matlab.ui.control.CheckBox
        AdaptScaleCB        matlab.ui.control.CheckBox
        RemovePreceedingCB  matlab.ui.control.CheckBox
        Spinner             matlab.ui.control.Spinner
        text4_2             matlab.ui.control.Label
        slider              matlab.ui.control.Slider
        PlotTypepopup       matlab.ui.control.DropDown
        Locpopup            matlab.ui.control.DropDown
        Xaxispopup          matlab.ui.control.DropDown
        Yaxispopup          matlab.ui.control.DropDown
        text5               matlab.ui.control.Label
        text4               matlab.ui.control.Label
        Elementpopup        matlab.ui.control.DropDown
        text7               matlab.ui.control.Label
        text8               matlab.ui.control.Label
        text6               matlab.ui.control.Label
        axes1               matlab.ui.control.UIAxes
        axes2               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp;
    end
    
    methods (Access = private)
        
        
        function [X, Y]=Plot_Response(app)
            global MainDirectory ProjectName ProjectPath Units  bsf_X bsf_Y
            clc;
            load(strcat(ProjectPath,ProjectName),'Filename','HStory','RESULTS','Brace_Data')
                        
            GMi          = app.MainApp.edit1.Value;
            Ri           = app.MainApp.editRi.Value;
            Element      = app.Elementpopup.Items{app.Elementpopup.Value};
            SpringLoc    = app.Locpopup.Value;
            PlotType     = app.PlotTypepopup.Value;
            Floor        = app.Yaxispopup.Value;
            Story        = app.Yaxispopup.Value;
            Axis         = app.Xaxispopup.Value;
            Bay          = app.Xaxispopup.Value;
            
            if app.CB1.Value==1
                [~,indxmax]=max(abs(bsf_X));
                app.slider.Value=indxmax;
                app.Spinner.Value=indxmax;
            end

            if app.CB2.Value==1
                [~,indxmax]=max(abs(bsf_Y));
                app.slider.Value=indxmax;
                app.Spinner.Value=indxmax;
            end

            Ti = app.Spinner.Value;
            if app.RemovePreceedingCB.Value==1; Tlast=Ti; else; Tlast=app.slider.Limits(2); end
            
            try

                % Plot
                [X,Y]=Plot_BSF_vs_SDR1(GMi, Ri, 0);
                cla(app.axes1);
                hold(app.axes1,"on");
                plot (app.axes1,X(1:Tlast),Y(1:Tlast), '-k','linewidth',1);
                app.axes1.XLabel.String='\itSDR\rm_1 [% rad]';
                app.axes1.YLabel.String='\itBSF\rm /\itW_s';
                if app.AdaptScaleCB.Value==0
                    app.axes1.XLim=[-max(abs(X))*1.2 max(abs(X))*1.2];
                    app.axes1.YLim=[-max(abs(Y))*1.2 max(abs(Y))*1.2];
                else
                    resetplotview(app.axes1)
                end
                plot (app.axes1, X(Ti), Y(Ti), 'ok','MarkerFaceColor','r');
                yline (app.axes1, 0, '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                xline (app.axes1, 0 , '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
            
                % Plot
                [yRootName, xRootName, Sub_RootName] = Plot_Local_Get_RootName    (Element, PlotType, Floor, Axis, SpringLoc, Filename);
                [XLabel, YLabel]                     = Plot_Local_Get_Labels      (Element, PlotType, Units);
                [DataColIndx]                        = Plot_Local_Get_DataColIndx (Element, PlotType, SpringLoc);
                [X, Y]                               = get_local_XYdata           (RESULTS, Element, PlotType, Story, Floor, Axis, Bay, yRootName, xRootName, Sub_RootName, DataColIndx, Brace_Data, HStory);

                cla(app.axes2);
                hold(app.axes2,"on");
                plot (app.axes2,X(1:Tlast),Y(1:Tlast), '-k','linewidth',1);
                app.axes2.XLabel.String=XLabel;
                app.axes2.YLabel.String=YLabel;
                if app.AdaptScaleCB.Value==0
                    app.axes2.XLim=[-max(abs(X))*1.2 max(abs(X))*1.2];
                    app.axes2.YLim=[-max(abs(Y))*1.2 max(abs(Y))*1.2];
                else    
                    resetplotview(app.axes2)
                end
                plot (app.axes2, X(Ti), Y(Ti), 'ok','MarkerFaceColor','r');
                xline (app.axes2, 0, '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                yline (app.axes2, 0, '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);


            catch ME
                cla(app.axes2);
                app.axes2.XLim=[0 1];
                app.axes2.YLim=[0 1];
                text(app.axes1,0.1,0.5,'Error generating plot!','FontSize',24,'Color','r','FontName','Times');
                text(app.axes2,0.1,0.5,'Error generating plot!','FontSize',24,'Color','r','FontName','Times');
                cd(MainDirectory)
                write_Error(ME);
            end
            
        end
        
        function UpdateLocPoup(app)
            global NStory NBay
            clc
            Element=app.Elementpopup.Items{app.Elementpopup.Value};
            if strcmp(Element,'Column Spring')==1
                Loc=app.Yaxispopup.Value;
                if Loc~=1 && Loc~=NStory+1
                    Z{1}='Top spring';
                    Z{2}='Bottom spring';
                elseif Loc==1
                    Z{1}='Top spring';
                elseif Loc==NStory+1
                    Z{1}='Bottom spring';
                end
            elseif strcmp(Element,'Beam Spring')==1
                Loc=app.Xaxispopup.Value;
                if Loc~=1 && Loc~=NBay+1
                    Z{1}='Left spring';
                    Z{2}='Right spring';
                elseif Loc==1
                    Z{1}='Right spring';
                elseif Loc==NBay+1
                    Z{1}='Left spring';
                end
            elseif strcmp(Element,'EGF Connection')==1
                Loc=app.Xaxispopup.Value;
                if Loc==1
                    Z{1}='Right spring';
                else
                    Z{1}='Left spring';
                end
            elseif strcmp(Element,'Panel Zone Spring')==1 || strcmp(Element,'Floor Link')==1
                Z{1}='--';
            elseif strcmp(Element,'Column Elastic Element')==1
                Z{1}='Top end';
                Z{2}='Bottom end';
            elseif strcmp(Element,'Beam Elastic Element')==1
                Z{1}='Left end';
                Z{2}='Right end';
            elseif strcmp(Element,'Brace')==1
                Z{1}='Left Brace';
                Z{2}='Right Brace';
            end
            app.Locpopup.Items=Z;
            app.Locpopup.ItemsData=1:length(Z);
            app.Locpopup.Value=1;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Visualize_Local_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectName ProjectPath NStory NBay bsf_X bsf_Y
            clc;
            load(strcat(ProjectPath,ProjectName),'NStory','NBay','Recorders','FrameType','EQ', 'PO','BRACES', 'MF_COLUMNS', 'MF_BEAMS','Units','fy','RESULTS', 'Column_Data')
            
            % Clear Figures
            cla(app.axes1);
            cla(app.axes2);

            GMi = app.MainApp.edit1.Value;
            Ri  = app.MainApp.editRi.Value;

            if EQ==1
                app.figure1.Name=['Visualize Local Response Progression: GM#',num2str(GMi)];
            elseif PO==1
                app.figure1.Name=['PVisualize Local Response Progression: Pushover'];
                app.AdaptScaleCB.Value=1;
            end

            if exist('Column_Data') ~=1
                [Column_Data, Beam_Data, Brace_Data] = Get_Member_Data  (NStory, NBay, FrameType, MF_COLUMNS, MF_BEAMS, BRACES, Units, fy);
                save(strcat(ProjectPath,ProjectName),'Column_Data', 'Beam_Data', 'Brace_Data','-append');
            end

            Time=RESULTS.Time;
            app.slider.Limits=[1 size(Time,1)];
            xx3=0:max(100,round(size(Time,1)/10)):size(Time,1); xx3(1,1)=1;
            app.slider.MajorTicks=xx3;
            app.slider.Value=ceil(size(Time,1)/2);
            app.Spinner.Value=ceil(size(Time,1)/2);
            app.Spinner.Limits=[1 size(Time,1)];

            [bsf_X, bsf_Y]=Plot_BSF_vs_SDR1(GMi, Ri, 0);

            % Plot_Frame(app);
            
            nRecorders=0;
            if Recorders.ColSpring==1;      Z{nRecorders+1}='Column Spring';            nRecorders=nRecorders+1; end
            if Recorders.BeamSpring==1;     Z{nRecorders+1}='Beam Spring';              nRecorders=nRecorders+1; end
            if Recorders.FloorLink==1;      Z{nRecorders+1}='Floor Link';               nRecorders=nRecorders+1; end
            if Recorders.PZ==1;             Z{nRecorders+1}='Panel Zone Spring';        nRecorders=nRecorders+1; end
            if Recorders.Column==1;         Z{nRecorders+1}='Column Elastic Element';   nRecorders=nRecorders+1; end
            if Recorders.Beam==1;           Z{nRecorders+1}='Beam Elastic Element';     nRecorders=nRecorders+1; end
            if Recorders.Brace==1;          Z{nRecorders+1}='Brace';                    nRecorders=nRecorders+1; end
            if Recorders.EGFconnection==1;  Z{nRecorders+1}='EGF Connection';           nRecorders=nRecorders+1; end
            
            app.Elementpopup.Items=Z;
            app.Elementpopup.ItemsData=1:nRecorders;
            app.Elementpopup.Value=1;
            Elementpopup_Callback(app)
            SpinnerValueChanged(app);
        end

        % Value changed function: Elementpopup
        function Elementpopup_Callback(app, event)
            global NStory NBay
            clc
            
            % Construct the Yaxispopup elements
            Element=app.Elementpopup.Items{app.Elementpopup.Value};
            if strcmp(Element,'Column Spring')==1
                app.text4.Text='Floor';
                Y{1}='Ground';
                for i=2:NStory+1
                    Y{i}=['Floor ',num2str(i)];
                end
                Y{end}='Roof';
            elseif strcmp(Element,'Beam Spring')==1 || strcmp(Element,'Panel Zone Spring')==1 || strcmp(Element,'Beam Elastic Element')==1 || strcmp(Element,'Floor Link')==1 || strcmp(Element,'EGF Connection')==1
                app.text4.Text='Floor';
                for i=2:NStory+1
                    Y{i-1}=['Floor ',num2str(i)];
                end
                Y{end}='Roof';
            elseif strcmp(Element,'Column Elastic Element')==1
                app.text4.Text='Story';
                Y{1}='Story 1';
                for i=2:NStory
                    Y{i}=['Story ',num2str(i)];
                end
            elseif strcmp(Element,'Brace')==1
                app.text4.Text='Story';
                Y{1}='Story 1';
                for i=2:NStory
                    Y{i}=['Story ',num2str(i)];
                end
            else
                app.text4.Text='Floor';
            end
            app.Yaxispopup.Items=Y;
            app.Yaxispopup.ItemsData=1:length(Y);
            app.Yaxispopup.Value=1;
            
            % Construct the Xaxispopup elements
            if strcmp(Element,'Column Spring')==1 || strcmp(Element,'Beam Spring')==1|| strcmp(Element,'Panel Zone Spring')==1 || strcmp(Element,'Column Elastic Element')==1
                app.text5.Text='Axis';
                X{1}='Axis 1';
                for i=2:NBay+1
                    X{i}=['Axis ',num2str(i)];
                end
            elseif strcmp(Element,'Beam Elastic Element')==1 || strcmp(Element,'Brace')==1
                app.text5.Text='Bay';
                X{1}='Bay 1';
                for i=2:NBay
                    X{i}=['Bay ',num2str(i)];
                end
            elseif strcmp(Element,'EGF Connection')==1
                app.text5.Text='Bay';
                X{1}=['Bay ',num2str(NBay+2)];
                X{2}=['Bay ',num2str(NBay+3)];
            elseif strcmp(Element,'Floor Link')==1
                app.text5.Text='Bay';
                X{1}=['Bay ',num2str(NBay+1)];
            end
            app.Xaxispopup.Items=X;
            app.Xaxispopup.ItemsData=1:length(X);
            app.Xaxispopup.Value=1;
            
            PlotTypepopup_Callback(app)
            Xaxispopup_Callback(app);
            
        end

        % Value changed function: Locpopup
        function Locpopup_Callback(app, event)
            Plot_Response(app);
        end

        % Value changed function: PlotTypepopup
        function PlotTypepopup_Callback(app, event)
            global MainDirectory
            
            cd(MainDirectory)
            Element=app.Elementpopup.Items{app.Elementpopup.Value};
            if strcmp(Element,'Column Spring')==1
                Z{1}='Moment-Rotation';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'Beam Spring')==1
                Z{1}='Moment-Rotation';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'EGF Connection')==1
                Z{1}='Moment-Rotation';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'Panel Zone Spring')==1
                Z{1}='Moment-Shear Distortion';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'Column Elastic Element')==1 || strcmp(app.Elementpopup.Items{app.Elementpopup.Value},'Beam Elastic Element')==1
                Z{1}='Axial Force-SDR1';
                Z{2}='Shear Force-SDR1';
                Z{3}='Moment-SDR1';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1:3;
            elseif strcmp(Element,'Brace')==1
                Z{1}='Axial Force-SDRi';
                Z{2}='Axial Force-SDR1';
                Z{3}='Axial Force-Axial Disp';
                Z{4}='Norm. Axial Force-Axial Disp';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1:4;
            elseif strcmp(Element,'Floor Link')==1
                Z{1}='Axial Force-SDR1';
                Z{2}='Axial Force-Axial Disp';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1:2;
            end
            Plot_Response(app);
        end

        % Value changed function: Xaxispopup
        function Xaxispopup_Callback(app, event)
            UpdateLocPoup (app);
            Plot_Response(app);
        end

        % Value changed function: Yaxispopup
        function Yaxispopup_Callback(app, event)
            UpdateLocPoup (app);
            Locpopup_Callback(app);
            Plot_Response(app);
        end

        % Value changed function: slider
        function sliderValueChanged(app, event)
            R_target = round(app.slider.Value);
            app.Spinner.Value = R_target;

            Plot_Response(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            X=app.Spinner.Value;
            app.slider.Value=X;
            sliderValueChanged(app)            
        end

        % Value changed function: RemovePreceedingCB
        function RemovePreceedingCBValueChanged(app, event)
            % if app.RemovePreceedingCB.Value==1
            %     app.AdaptScaleCB.Enable='on';
            % else
            %     app.AdaptScaleCB.Enable='off';
            %     app.AdaptScaleCB.Value=0;
            % end
            Plot_Response(app);            
        end

        % Value changed function: AdaptScaleCB
        function AdaptScaleCBValueChanged(app, event)
            Plot_Response(app);                        
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value==1; app.CB2.Value=0; end 
            Plot_Response(app);                        
        end

        % Value changed function: CB2
        function CB2ValueChanged(app, event)
            if app.CB2.Value==1; app.CB1.Value=0; end 
            Plot_Response(app);                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Visualize Local Response Progression';
            app.figure1.Tag = 'figure1';
            app.axes2 = uiaxes(app.figure1);
            xlabel(app.axes2, '\it\theta\rm [% rad]')
            ylabel(app.axes2, '\itM\rm')
            app.axes2.Tag = 'axes2';
            app.axes1 = uiaxes(app.figure1);
            xlabel(app.axes1, '\itSDR\rm_1 [% rad]')
            ylabel(app.axes1, '\itBSF\rm /\itW_s')
            app.axes1.Tag = 'axes2';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Element';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Plot type';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Location';
            app.Elementpopup = uidropdown(app.figure1);
            app.Elementpopup.Items = {'Column Spring', 'Beam Spring', 'Panel Zone Spring', 'Column Elastic Element', 'Beam Elastic Element', 'EGF Connection', 'Floor Link', 'Brace'};
            app.Elementpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Elementpopup.ValueChangedFcn = createCallbackFcn(app, @Elementpopup_Callback, true);
            app.Elementpopup.Tag = 'Elementpopup';
            app.Elementpopup.Value = '1';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Floor';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Axis';
            app.Yaxispopup = uidropdown(app.figure1);
            app.Yaxispopup.Items = {'Story 1'};
            app.Yaxispopup.ValueChangedFcn = createCallbackFcn(app, @Yaxispopup_Callback, true);
            app.Yaxispopup.Tag = 'Yaxispopup';
            app.Yaxispopup.Value = 'Story 1';
            app.Xaxispopup = uidropdown(app.figure1);
            app.Xaxispopup.Items = {'Axis 1'};
            app.Xaxispopup.ValueChangedFcn = createCallbackFcn(app, @Xaxispopup_Callback, true);
            app.Xaxispopup.Tag = 'Xaxispopup';
            app.Xaxispopup.Value = 'Axis 1';
            app.Locpopup = uidropdown(app.figure1);
            app.Locpopup.Items = {'Top', 'Bottom'};
            app.Locpopup.ValueChangedFcn = createCallbackFcn(app, @Locpopup_Callback, true);
            app.Locpopup.Tag = 'Locpopup';
            app.Locpopup.Value = 'Top';
            app.PlotTypepopup = uidropdown(app.figure1);
            app.PlotTypepopup.Items = {'Axial Load-Rotation', 'Shear Force-Rotation', 'Moment-Rotation'};
            app.PlotTypepopup.ItemsData = {'1', '2', '3'};
            app.PlotTypepopup.ValueChangedFcn = createCallbackFcn(app, @PlotTypepopup_Callback, true);
            app.PlotTypepopup.Tag = 'PlotTypepopup';
            app.PlotTypepopup.Value = '1';
            app.slider = uislider(app.figure1);
            app.slider.Limits = [1 1000000];
            app.slider.MajorTicks = [];
            app.slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.slider.MinorTicks = [];
            app.slider.Value = 1;
            app.text4_2 = uilabel(app.figure1);
            app.text4_2.Text = 'Step:';
            app.Spinner = uispinner(app.figure1);
            app.Spinner.Limits = [1 10000];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.RemovePreceedingCB = uicheckbox(app.figure1);
            app.RemovePreceedingCB.ValueChangedFcn = createCallbackFcn(app, @RemovePreceedingCBValueChanged, true);
            app.RemovePreceedingCB.Text = 'remove succeeding';
            app.AdaptScaleCB = uicheckbox(app.figure1);
            app.AdaptScaleCB.ValueChangedFcn = createCallbackFcn(app, @AdaptScaleCBValueChanged, true);
            app.AdaptScaleCB.Text = 'adaptive scale';
            app.CB1 = uicheckbox(app.figure1);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'Go to peak drift';
            app.CB2 = uicheckbox(app.figure1);
            app.CB2.ValueChangedFcn = createCallbackFcn(app, @CB2ValueChanged, true);
            app.CB2.Text = 'Go to peak strength';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_Response_Progression_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Visualize_Local_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end